classdef PROJECT_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PITCHTUNERCORRECTORTab          matlab.ui.container.Tab
        GENERATEMIDIFILESANDMUSICSHEETSPanel  matlab.ui.container.Panel
        MUSICSHEETButton                matlab.ui.control.Button
        MIDIFILESButton                 matlab.ui.control.Button
        EditField_6                     matlab.ui.control.EditField
        BROWSEButton_3                  matlab.ui.control.Button
        PITCHOPERATIONPanel             matlab.ui.container.Panel
        SELECTAPARTOFPITCHButton        matlab.ui.control.Button
        SHIFTPITCHButton                matlab.ui.control.Button
        EDITINGOPTIONPanel              matlab.ui.container.Panel
        CUTButton                       matlab.ui.control.Button
        JOINButton                      matlab.ui.control.Button
        DELETEButton                    matlab.ui.control.Button
        REPLICATEButton                 matlab.ui.control.Button
        PLOTPanel                       matlab.ui.container.Panel
        SUPERIMPOSEButton               matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        MODIFIEDButton                  matlab.ui.control.Button
        ORIGINALButton                  matlab.ui.control.Button
        PLOTTYPEDropDown                matlab.ui.control.DropDown
        PLOTTYPEDropDownLabel           matlab.ui.control.Label
        SCALEANDCHORDDETECTIONPanel     matlab.ui.container.Panel
        CHECKButton_2                   matlab.ui.control.Button
        CHORDEditField                  matlab.ui.control.EditField
        CHORDEditFieldLabel             matlab.ui.control.Label
        SCALEEditField                  matlab.ui.control.EditField
        SCALEEditFieldLabel             matlab.ui.control.Label
        CHECKButton                     matlab.ui.control.Button
        AUDIOEFFECTSPanel               matlab.ui.container.Panel
        APPLYButton_2                   matlab.ui.control.Button
        APPLYButton                     matlab.ui.control.Button
        TEMPOLabel                      matlab.ui.control.Label
        VIBRATOLabel                    matlab.ui.control.Label
        TEMPOFACTORSlider_2             matlab.ui.control.Slider
        TEMPOFACTORLabel                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        FREQUENCYSlider                 matlab.ui.control.Slider
        FREQUENCYSliderLabel            matlab.ui.control.Label
        SAVEUNDOPanel                   matlab.ui.container.Panel
        UNDOButton                      matlab.ui.control.Button
        SAVEButton                      matlab.ui.control.Button
        PLAYOPTIONPanel                 matlab.ui.container.Panel
        ORIGINALAUDIOButton             matlab.ui.control.Button
        MODIFIEDAUDIOButton             matlab.ui.control.Button
        RECORDOPTIONPanel               matlab.ui.container.Panel
        STOPButton                      matlab.ui.control.Button
        RECORDButton                    matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        BROWSEButton                    matlab.ui.control.Button
        TYPEOFAUDIOTOBEUSEDButtonGroup  matlab.ui.container.ButtonGroup
        RECORDLIVEButton                matlab.ui.control.RadioButton
        RECORDEDAUDIOFILEButton         matlab.ui.control.RadioButton
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        PRACTICESINGINGTab              matlab.ui.container.Tab
        PLAYOPTIONPanel_2               matlab.ui.container.Panel
        REFERENCEButton_2               matlab.ui.control.Button
        RECORDEDButton_2                matlab.ui.control.Button
        RECORDOPTIONPanel_2             matlab.ui.container.Panel
        STOPButton_2                    matlab.ui.control.Button
        RECORDButton_2                  matlab.ui.control.Button
        PLOTPanel_2                     matlab.ui.container.Panel
        CLEARButton_2                   matlab.ui.control.Button
        SUPERIMPOSEButton_2             matlab.ui.control.Button
        RECORDEDButton                  matlab.ui.control.Button
        REFERENCEButton                 matlab.ui.control.Button
        ACCURACYISEditField             matlab.ui.control.NumericEditField
        ACCURACYISEditFieldLabel        matlab.ui.control.Label
        CHECKACCURACYButton             matlab.ui.control.Button
        RESTARTRERECORDPanel            matlab.ui.container.Panel
        RERECORDButton                  matlab.ui.control.Button
        RESTARTButton                   matlab.ui.control.Button
        OFERRORTHRESHOLDALLOWEDSlider   matlab.ui.control.Slider
        OFERRORTHRESHOLDALLOWEDSliderLabel  matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        DONEButton                      matlab.ui.control.Button
        UPLOADButton                    matlab.ui.control.Button
        DURATIONinsEditField            matlab.ui.control.NumericEditField
        DURATIONinsEditFieldLabel       matlab.ui.control.Label
        SELECTNOTEDropDown              matlab.ui.control.DropDown
        SELECTNOTEDropDownLabel         matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        BROWSEButton_2                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UPLOADAUDIOTABLEButtonGroup     matlab.ui.container.ButtonGroup
        UPLOADTABLEWITHPITCHTIMEDETAILSButton  matlab.ui.control.RadioButton
        UPLOADAREFERENCEAUDIOFILEButton  matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file
            path
            freqref         %referencefrequency or referencepitch matrix
            timeref         %reference time matrix
            xtref
            timeact         %recorded time matrix
            freqact         %recorded frequency matrix
            xtact
            totalduration   %total duration of the reference audio
            Fs              %sampling frequency
            originalxt      %time domain matrix
            originaltime    %time matrix
            originalfreq    %original frequency matrix
            modifiedxt      %modified audio matrix
            modifiedtime    %modified time matrix
            modifiedfreq    %modified frequency matrix
            music_note_frequency = py.dict(pyargs('SILENCE',0,'C0',16.35,'C#0',17.32,'D0',18.35,'D#0',19.45,'E0',20.6,'F0',21.83,'F#0',23.12,'G0',24.50,'G#0',25.96,'A0',27.50,'A#0',29.14,'B0',30.87,'C1',32.7,'C#1',34.65,'D1',36.71,'D#1',38.89,'E1',41.2,'F1',46.25,'F#1',46.25,'G1',49,'G#1',51.91,'A1',55,'A#1',58.27,'B1',61.74,'C2',65.41,'C#2',69.30,'D2',73.42,'D#2',77.78,'E2',82.41,'F2',87.31,'F#2',92.5,'G2',98,'G#2',103.83,'A2',110,'A#2',116.54,'B2',123.47,'C3',130.81,'C#3',138.59,'D3',146.83,'D#3',155.56,'E3',164.81,'F3',174.61,'F#3',185,'G3',196,'G#3',207.65,'A3',220,'A#3',233.08,'B3',246.94,'C4',261.63,'C#4',277.18,'D4',293.66,'D#4',311.13,'E4',329.63,'F4',349.23,'F#4',370,'G4',392,'G#4',415.30,'A4',440,'A#4',466.16,'B4',493.88,'C5',523.25,'C#5',554.37,'D5',587.33,'D#5',622.25,'E5',659.25,'F5',698.46,'F#5',740,'G5',784,'G#5',830.61,'A5',880,'A#5',932.33,'B5',987.77,'C6',1046.5,'C#6',1108.73,'D6',1174.67,'D#6',1244.51,'E6',1318.51,'F6',1396.91,'F#6',1480.0,'G6',1568,'G#6',1661.22,'A6',1760,'A#6',1864.67,'B6',1975.53));
            %list of frequency and its note
            music_scale = py.dict(pyargs('C Major',py.list({'C','D','E','F','G','A','B'}),'C# Major',py.list({'C#','D#','F','F#','G#','A#','C'}),'D Major',py.list({'D','E','F#','G','A','B','C#'}),'D# Major',py.list({'D#','F','G','G#','A','C','D'}),'E Major',py.list({'E','F#','G#','A','B','C#','D#'}),'F Major',py.list({'F','G','A','A#','C','D','E'}),'F# Major',py.list({'F#','G#','A#','B','C#','D#','F'}),'G Major',py.list({'G','A','B','C','D','E','F#'}),'G# Major',py.list({'G#','A#','C','C#','D#','F','G'}),'A Major',py.list({'A','B','C#','D','E','F#','G#'}),'A# Major',py.list({'A#','C','D','D#','F','G','A'}),'B Major',py.list({'B','C#','D#','E','F#','G#','A#'}),'E Major',py.list({'E','F#','G#','A','B','C#','D#'}),'C Minor',py.list({'C','D','D#','F','G','G#','A#'}),'C# Minor',py.list({'C#','D#','E','F#','G#','A','B'}),'D Minor',py.list({'D','E','F','G','A','A#','C#'}),'D# Minor',py.list({'D#','F','F#','G#','A#','B','C#'}),'E Minor',py.list({'E','F#','G','A','B','C','D'}),'F Minor',py.list({'F','G','G#','A#','C','C#','D#'}),'F# Minor',py.list({'F#','G#','A','B','C#','D','E'}),'G Minor',py.list({'G','A','A#','C','D','D#','F'}),'G# Minor',py.list({'G#','A#','B','C#','D#','E','F#'}),'A Minor',py.list({'A','B','C','D','E','F','G'}),'A# Minor',py.list({'A#','C','C#','D#','F','F#','G#'}),'B Minor',py.list({'B','C#','D','E','F#','G','A'}),'C Major Pentatonic',py.list({'C','D','E','G','A'}),'C# Major Pentatonic',py.list({'C#','D#','F','G#','A#'}),'D Major Pentatonic',py.list({'D','E','F#','A','B'}),'D# Major Pentatonic',py.list({'D#','F','G','A#','C'}),'E Major Pentatonic',py.list({'E','F#','G#','B','C#'}),'F Major Pentatonic',py.list({'F','G','A','C','D'}),'F# Major Pentatonic',py.list({'F#','G#','A#','C#','D#'}),'G Major Pentatonic',py.list({'G','A','B','D','E'}),'G# Major Pentatonic',py.list({'G#','A#','C','D#','F'}),'A Major Pentatonic',py.list({'A','B','C#','E','F#'}),'A# Major Pentatonic',py.list({'A#','C','D','F','G'}),'B Major Pentatonic',py.list({'B','C#','D#','F#','G#'}),'C Minor Pentatonic',py.list({'C','D#','F','G','A#'}),'C# Minor Pentatonic',py.list({'C#','E','F#','G#','B'}),'D Minor Pentatonic',py.list({'D','F','G','A','C'}),'D# Minor Pentatonic',py.list({'D#','F#','G#','A#','C#'}),'E Minor Pentatonic',py.list({'E','G','A','B','D'}),'F Minor Pentatonic',py.list({'F','G#','A#','C','D#'}),'F# Minor Pentatonic',py.list({'F#','A','B','C#','E'}),'G Minor Pentatonic',py.list({'G','A#','C','D','F'}),'G# Minor Pentatonic',py.list({'G#','B','C#','D#','F#'}),'A Minor Pentatonic',py.list({'A','C','D','E','G'}),'A# Minor Pentatonic',py.list({'A#','C#','D#','F','G#'}),'B Minor Pentatonic',py.list({'B','D','E','F#','A'})));
            %list of scales
            musical_note = py.list({'A','A#','B','C','C#','D','D#','E','F','F#','G','G#'});
            %list of all notes
            Frequency
            startindex
            endindex
            xt
            segmentedxt
            remainingxt;
            ChangeData
            ChangeTempoData
            IsRecording  % Flag to start / stop recording
            audioData    % Recorded samples of audio
            audioRecorderHandle % Handle for the audio recorder obj
    end
    
    methods (Access = private)
        
        function plotPitchLive(app)
            % Sampling frequency
            app.Fs
            
            % get the max and min y values
            markerData = [app.UIAxes1.YLim(1) app.UIAxes.YLim(2)];
            
            % plot the marker
            plot(app.UIAxes1, zeros(size(markerData)), markerData, 'r');

            % create an audiorecorder object
            app.audioRecorderHandle = audiorecorder(app.Fs, 16, 1);

            % setup the timer function to be called repeatedly every 0.5 seconds
            app.audioRecorderHandle.TimerFcn = {@app.calculatePitch, app.Fs};
            app.audioRecorderHandle.TimerPeriod = 0.2;
            disp('Recording!!');
            tic;
            record(app.audioRecorderHandle);

        end
        
        function calculatePitch(    ...
            app,                    ...
            audioRecorderHandle,    ...
            ~,                      ...
            Fs)
            if strcmp(audioRecorderHandle.Running, 'on')
                % Get the sample number of the previous recording
                previousLastSample = length(app.audioData);
                % Find the marker
                hMarker = findobj(app.UIAxes1, 'Color', 'r');
                delete(hMarker);
                % Get the audio recorded
                app.audioData = getaudiodata(audioRecorderHandle);
                % Convert the samples to time
                timePrevious = previousLastSample / Fs;
                % Compute pitch
                [freq, t] = YinAlgo(app.audioData(previousLastSample + 1 : end), Fs);
                % Plot the marker
                markerData = [app.UIAxes1.YLim(1) app.UIAxes.YLim(2)];
                plot(app.UIAxes1, repmat(t(end) + timePrevious, size(markerData)), markerData, 'r');
                % Plot the pitch
                plot(app.UIAxes1, t + timePrevious, freq,"LineWidth",1.5,"Color",[0 0 1]);
                tStartIdx = (t(end) + timePrevious - 3 > 0)*(t(end) + timePrevious - 3);
                xlim(app.UIAxes1, [tStartIdx t(end)+timePrevious+2]);
                hold(app.UIAxes1, "on");
            end
       end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: UPLOADAUDIOTABLEButtonGroup
        function UPLOADAUDIOTABLEButtonGroupSelectionChanged(app, event)
            %selectedButton = app.UPLOADAUDIOTABLEButtonGroup.SelectedObject;
            if app.UPLOADAREFERENCEAUDIOFILEButton.Value
                app.BROWSEButton_2.Enable = 'on';
                app.UPLOADButton.Enable = 'off';
                app.DONEButton.Enable = 'off';
                app.REFERENCEButton_2.Enable = 'on';
            elseif app.UPLOADTABLEWITHPITCHTIMEDETAILSButton.Value
                app.BROWSEButton_2.Enable = 'off';
                app.UPLOADButton.Enable = 'on';
                app.DONEButton.Enable = 'on';
                app.REFERENCEButton_2.Enable = 'off';
            end
        end

        % Button pushed function: BROWSEButton_2
        function BROWSEButton_2Pushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            app.EditField_4.Value = app.file;
            disp([app.path app.file]);
            string([app.path app.file])
            [app.xtref, app.Fs] = audioread(string([app.path app.file]));
            app.xtref = app.xtref(:,1);
            [app.freqref,app.timeref] = YinAlgo(app.xtref,app.Fs);
        end

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
            Note = app.SELECTNOTEDropDown.Value;
            Time = app.DURATIONinsEditField.Value;
            newrow = {Note Time};
            app.UITable.Data = [app.UITable.Data ; newrow];
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "NO FURTHUR UPLOADING TO TABLE WILL BE ALLOWED","STOP UPLOADING TO TABLE??", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                app.freqref = [];  
                app.Fs = 48000;
                app.totalduration = 0;
                for j = 1 : size(app.UITable.Data, 1)
                    app.freqref = [app.freqref app.music_note_frequency{app.UITable.Data{j, 1}}*ones(1, app.Fs*app.UITable.Data{j, 2})];
                    app.totalduration = app.totalduration + app.Fs*app.UITable.Data{j, 2} ;
                end
                app.timeref = linspace(0,length(app.freqref)/app.Fs,length(app.freqref));
            end
        end

        % Button pushed function: RECORDButton_2
        function RECORDButton_2Pushed(app, event)
            app.IsRecording = true;
            if ~(isempty(app.freqref))
                cla(app.UIAxes1)
                f = app.UIFigure;
                d = uiprogressdlg(f, "Title", "RECORD AUDIO", "Message", "Please Wait");
                pause(0.5);
                d.Value = 0.33;
                d.Message = "RECORDING STARTS IN 3";
                pause(1);
                d.Value = 0.66;
                d.Message = "RECORDING STARTS IN 2";
                pause(1);
                d.Value = 0.99;
                d.Message = "RECORDING STARTS IN 1";
                pause(1);
                d.Value = 1;
                d.Message = "START";
                pause(0.5);
                close(d);
                cla(app.UIAxes1)
                app.IsRecording = true;
                hold(app.UIAxes1,'on');
                    plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1.5,"Color",[0.6 0 0.3]);
                %ENABLING ND DISABLING
                app.BROWSEButton_2.Enable = 'off';
                app.SELECTNOTEDropDown.Enable = 'off';
                app.UPLOADButton.Enable = 'off';
                app.DONEButton.Enable = 'off';
                app.RESTARTButton.Enable = 'off';
                app.RERECORDButton.Enable = 'off';
                app.CHECKACCURACYButton.Enable = 'off';
                app.REFERENCEButton.Enable = 'off';
                app.RECORDEDButton.Enable = 'off';
                app.SUPERIMPOSEButton_2.Enable = 'off';
                app.CLEARButton_2.Enable = 'off';
                app.RECORDButton_2.Enable = 'off';
                app.STOPButton_2.Enable = 'on';
                app.REFERENCEButton_2.Enable = 'off';
                app.RECORDEDButton_2.Enable = 'off';
                
                app.plotPitchLive();
                
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE PROVIDE A REFERENCE FILE!!", "REFERENCE FILE NOT FOUND", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
            app.IsRecording = false;
            stop(app.audioRecorderHandle);
            %ENABLIND ND DISABLING
            app.BROWSEButton_2.Enable = 'on';
            app.SELECTNOTEDropDown.Enable = 'on';
            app.UPLOADButton.Enable = 'on';
            app.DONEButton.Enable = 'on';
            app.RESTARTButton.Enable = 'on';
            app.RERECORDButton.Enable = 'on';
            app.CHECKACCURACYButton.Enable = 'on';
            app.REFERENCEButton.Enable = 'on';
            app.RECORDEDButton.Enable = 'on';
            app.SUPERIMPOSEButton_2.Enable = 'on';
            app.CLEARButton_2.Enable = 'on';
            app.RECORDButton_2.Enable = 'on';
            app.STOPButton_2.Enable = 'off';
            app.REFERENCEButton_2.Enable = 'on';
            app.RECORDEDButton_2.Enable = 'on';
            toc;
            hold(app.UIAxes, "off");
            app.audioData = getaudiodata(app.audioRecorderHandle);
            size(app.audioData)
            app.xtact = app.audioData;
            app.xtact = app.xtact(1:length(app.freqref));
            size(app.xtact)
            app.timeact = (0 : length(app.xtact)-1)/app.Fs;
            [app.freqact, app.timeact] = YinAlgo(app.xtact,app.Fs);
            xlim(app.UIAxes1, [floor(app.timeact(1)) ceil(app.timeact(end))]);
        end

        % Button pushed function: REFERENCEButton
        function REFERENCEButtonPushed(app, event)
            cla(app.UIAxes1)
            plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1.5,"Color",[0.6 0 0.3])
        end

        % Button pushed function: RECORDEDButton
        function RECORDEDButtonPushed(app, event)
            cla(app.UIAxes1)
            plot(app.UIAxes1,app.timeact,app.freqact,"LineWidth",1.5,"Color",[0 0 1]);
        end

        % Button pushed function: SUPERIMPOSEButton_2
        function SUPERIMPOSEButton_2Pushed(app, event)
            cla(app.UIAxes1)
            plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1.5,"Color",[0.6 0 0.3]);
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.timeact,app.freqact,"LineWidth",1.5,"Color",[0 0 1]);
            hold(app.UIAxes1,'off');
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            cla(app.UIAxes1)
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU WANT TO RESTART EVERYTHING ?","RECORDED AUDIO AND TABLE WILL BE ERASED", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                app.UITable.Data = {};
                cla(app.UIAxes1);
                app.freqact = [];
                app.xtact = [];
                app.freqref = [];
                app.timeact = [];
                app.timeref = [];
            end
        end

        % Button pushed function: RERECORDButton
        function RERECORDButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU WANT TO RERECORD ?", "RECORDED AUDIO WILL BE ERASED", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                clear app.freqact,app.xtact;
            end
        end

        % Value changing function: OFERRORTHRESHOLDALLOWEDSlider
        function OFERRORTHRESHOLDALLOWEDSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = changingValue;
        end

        % Button pushed function: CHECKACCURACYButton
        function CHECKACCURACYButtonPushed(app, event)
            start = 1;
            crt_frame = 0;
            no_frame = 0;
            win_len = 2048;
            app.OFERRORTHRESHOLDALLOWEDSlider.Value
            while start < length(app.freqref)
                freq_dev = app.freqref(start) * app.OFERRORTHRESHOLDALLOWEDSlider.Value/100;
                max_freq = app.freqref(start) + freq_dev;
                min_freq = app.freqref(start) - freq_dev;
                if (min_freq <=app.freqact(start)) && (app.freqact(start) <= max_freq)
                    crt_frame = crt_frame + 1;
                end
                no_frame = no_frame + 1;
                start = start + win_len;
            end
            f = app.UIFigure;
            d = uiprogressdlg(f, "Title", "CHECK ACCURACY", "Message", "Please Wait");
            pause(0.5);
            d.Value = 0.33;
            d.Message = "COLLECTING REFERENCE AUDIO";
            pause(1);
            d.Value = 0.66;
            d.Message = "COLLECTING RECORDED AUDIO";
            pause(1);
            d.Value = 0.99;
            d.Message = "COMPARING REFERENCE AND RECORDED AUDIO";
            pause(1);
            d.Value = 1;
            d.Message = "ACCURACY IS";
            pause(1);
            close(d);
            app.ACCURACYISEditField.Value = crt_frame/no_frame*100;
        end

        % Button pushed function: REFERENCEButton_2
        function REFERENCEButton_2Pushed(app, event)
            if ~(isempty(app.freqref))
                cla(app.UIAxes1)
                plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1.5,"Color",[0.6 0 0.3])
                end_time = length(app.freqref)/app.Fs;
                h=line(app.UIAxes1,[0,0],[0,max(app.freqref)+50],'color','r');
                sound(app.xtref, app.Fs)
                tic
                z=toc;
                while z<end_time
                    set(h, 'xdata', z*[1,1])
                    drawnow
                    z=toc;
                end
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE PROVIDE A REFERENCE FILE !!", "REFERENCE NOT FOUND ", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: RECORDEDButton_2
        function RECORDEDButton_2Pushed(app, event)
            if ~(isempty(app.freqact))
                cla(app.UIAxes1)
                plot(app.UIAxes1,app.timeact,app.freqact,"LineWidth",1.5,"Color",[0 0 01])
                end_time = length(app.freqref)/app.Fs;
                h=line(app.UIAxes1,[0,0],[0,max(app.freqact)+50],'color','r');
                sound(app.xtact, app.Fs)
                tic
                z=toc;
                while z<end_time
                    set(h, 'xdata', z*[1,1])
                    drawnow
                    z=toc;
                end
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE RECORD AN AUDIO !!", "RECORDING NOT FOUND", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: BROWSEButton
        function BROWSEButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            app.EditField.Value = app.file;
            disp([app.path app.file]);
            string([app.path app.file]);
            [app.originalxt, app.Fs] = audioread(string([app.path app.file]));
            app.originalxt = app.originalxt(:,1);
            app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
            [app.originalfreq, app.originaltime] = YinAlgo(app.originalxt,app.Fs);
            app.modifiedxt = app.originalxt;
            app.modifiedfreq = app.originalfreq;
            app.modifiedtime = app.originaltime;
        end

        % Selection changed function: TYPEOFAUDIOTOBEUSEDButtonGroup
        function TYPEOFAUDIOTOBEUSEDButtonGroupSelectionChanged(app, event)
            %selectedButton = app.TYPEOFAUDIOTOBEUSEDButtonGroup.SelectedObject;
            if app.RECORDEDAUDIOFILEButton.Value
                app.BROWSEButton.Enable = 'on';
                app.RECORDButton.Enable = 'off';
                app.STOPButton.Enable = 'off';
            elseif app.RECORDLIVEButton.Value
                app.BROWSEButton.Enable = 'off';
                app.RECORDButton.Enable = 'on';
                app.STOPButton.Enable = 'on';
            end
        end

        % Button pushed function: SELECTAPARTOFPITCHButton
        function SELECTAPARTOFPITCHButtonPushed(app, event)
            cla(app.UIAxes)
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes,app.modifiedtime,app.modifiedfreq,"LineWidth",1.5,"Color",[0 0 1]);
            hold(app.UIAxes,'on')
            [x,~] = selectDatapoints(app.UIAxes);
            app.startindex = round(x(1)*app.Fs);
            app.endindex = round(x(end)*app.Fs);
            plot(app.UIAxes,app.modifiedtime(app.startindex:app.endindex),app.modifiedfreq(app.startindex:app.endindex),"LineWidth",1.5,"Color",[1 0 0])
            hold(app.UIAxes,'off')
            function [x,y] = selectDatapoints(ax)
                % Remove any pre-existing rectanlges (if any)
                delete(findall(ax, 'Type',  'images.roi.Rectangle'))
                
                % Get coordinates of all data points in the axes
                xyobj = findall(ax.Children, '-Property','xData');
                xydata = get(xyobj, {'XData','YData'});
                xydataMat = cell2mat(xydata');
                
                
                pan(app.UIAxes, 'off') %turn off panning so the interaction doesn't drag the data.
                roi = drawrectangle(ax);
                
                % quit if figure is closed
                if ~isvalid(roi)
                    x = [];
                    y = [];
                    return
                end
                
                % determine which coordinates are within the ROI
                isIn = xydataMat(1,:) >= roi.Position(1) & xydataMat(1,:) <= sum(roi.Position([1,3])) ...
                    & xydataMat(2,:) >= roi.Position(2) & xydataMat(2,:) <= sum(roi.Position([2,4]));
                
                % Delete ROI
                delete(roi)
                
                % Return outputs
                x = xydataMat(1,isIn);
                y = xydataMat(2,isIn);
                disp([ x(1),y(1)]);
                disp([x(end),y(end)]);
            end
        end

        % Button pushed function: ORIGINALButton
        function ORIGINALButtonPushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes)
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                plot(app.UIAxes,app.originaltime,app.originalxt,"Color",[0.6 0 0.3]);
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes)
                app.originaltime = (0 : length(app.originalfreq)-1)/app.Fs;
                plot(app.UIAxes,app.originaltime,app.originalfreq,"LineWidth",1.5,"Color",[0.6 0 0.3])
            end
        end

        % Button pushed function: MODIFIEDButton
        function MODIFIEDButtonPushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes)
                app.modifiedtime = (0 : length(app.modifiedxt)-1)/app.Fs;
                plot(app.UIAxes,app.modifiedtime,app.modifiedxt,"Color",[0 0 1]);
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes)
                app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
                plot(app.UIAxes,app.modifiedtime,app.modifiedfreq,"LineWidth",1.5,"Color",[0 0 1]);
            end
        end

        % Button pushed function: SUPERIMPOSEButton
        function SUPERIMPOSEButtonPushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes)
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                plot(app.UIAxes,app.originaltime,app.originalxt,"Color",[0.6 0 0.3]);
                hold(app.UIAxes,'on')
                app.modifiedtime = (0 : length(app.modifiedxt)-1)/app.Fs;
                plot(app.UIAxes,app.modifiedtime,app.modifiedxt,"Color",[0 0 1]);
                hold(app.UIAxes,'off')
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes)
                app.originaltime = (0 : length(app.originalfreq)-1)/app.Fs;
                plot(app.UIAxes,app.originaltime,app.originalfreq,"LineWidth",1.5,"Color",[0.6 0 0.3])
                hold(app.UIAxes,'on')
                app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
                plot(app.UIAxes,app.modifiedtime,app.modifiedfreq,"LineWidth",1.5,"Color",[0 0 1]);
                hold(app.UIAxes,'off')
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PITCHTUNERCORRECTORTab = uitab(app.TabGroup);
            app.PITCHTUNERCORRECTORTab.Title = 'PITCH TUNER/CORRECTOR';
            app.UIAxes = uiaxes(app.PITCHTUNERCORRECTORTab);
            title(app.UIAxes, 'PITCH/WAVEFORM PLOT')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'AMPLITUDE/ PITCH')
            app.Image = uiimage(app.PITCHTUNERCORRECTORTab);
            app.Image.ImageSource = 'PITCHTUNER.jpg';
            app.TYPEOFAUDIOTOBEUSEDButtonGroup = uibuttongroup(app.PITCHTUNERCORRECTORTab);
            app.TYPEOFAUDIOTOBEUSEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TYPEOFAUDIOTOBEUSEDButtonGroupSelectionChanged, true);
            app.TYPEOFAUDIOTOBEUSEDButtonGroup.Title = 'TYPE OF AUDIO TO BE USED';
            app.RECORDEDAUDIOFILEButton = uiradiobutton(app.TYPEOFAUDIOTOBEUSEDButtonGroup);
            app.RECORDEDAUDIOFILEButton.Text = 'RECORDED AUDIO FILE';
            app.RECORDLIVEButton = uiradiobutton(app.TYPEOFAUDIOTOBEUSEDButtonGroup);
            app.RECORDLIVEButton.Text = 'RECORD LIVE';
            app.RECORDLIVEButton.Value = true;
            app.BROWSEButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.BROWSEButton.ButtonPushedFcn = createCallbackFcn(app, @BROWSEButtonPushed, true);
            app.BROWSEButton.Enable = 'off';
            app.BROWSEButton.Text = 'BROWSE';
            app.EditField = uieditfield(app.PITCHTUNERCORRECTORTab, 'text');
            app.EditField.Enable = 'off';
            app.RECORDOPTIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.RECORDOPTIONPanel.Title = 'RECORD OPTION';
            app.RECORDButton = uibutton(app.RECORDOPTIONPanel, 'push');
            app.RECORDButton.Text = 'RECORD';
            app.STOPButton = uibutton(app.RECORDOPTIONPanel, 'push');
            app.STOPButton.Text = 'STOP';
            app.PLAYOPTIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.PLAYOPTIONPanel.Title = 'PLAY OPTION';
            app.MODIFIEDAUDIOButton = uibutton(app.PLAYOPTIONPanel, 'push');
            app.MODIFIEDAUDIOButton.Text = 'MODIFIED AUDIO';
            app.ORIGINALAUDIOButton = uibutton(app.PLAYOPTIONPanel, 'push');
            app.ORIGINALAUDIOButton.Text = 'ORIGINAL AUDIO';
            app.SAVEUNDOPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.SAVEUNDOPanel.Title = 'SAVE/UNDO';
            app.SAVEButton = uibutton(app.SAVEUNDOPanel, 'push');
            app.SAVEButton.Text = 'SAVE';
            app.UNDOButton = uibutton(app.SAVEUNDOPanel, 'push');
            app.UNDOButton.Text = 'UNDO';
            app.AUDIOEFFECTSPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.AUDIOEFFECTSPanel.Title = 'AUDIO EFFECTS';
            app.FREQUENCYSliderLabel = uilabel(app.AUDIOEFFECTSPanel);
            app.FREQUENCYSliderLabel.Text = 'FREQUENCY';
            app.FREQUENCYSlider = uislider(app.AUDIOEFFECTSPanel);
            app.FREQUENCYSlider.Limits = [1 10];
            app.FREQUENCYSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FREQUENCYSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.FREQUENCYSlider.Value = 1;
            app.EditField_2 = uieditfield(app.AUDIOEFFECTSPanel, 'numeric');
            app.EditField_3 = uieditfield(app.AUDIOEFFECTSPanel, 'numeric');
            app.TEMPOFACTORLabel = uilabel(app.AUDIOEFFECTSPanel);
            app.TEMPOFACTORLabel.Text = {'TEMPO'; 'FACTOR'};
            app.TEMPOFACTORSlider_2 = uislider(app.AUDIOEFFECTSPanel);
            app.TEMPOFACTORSlider_2.Limits = [0 4];
            app.TEMPOFACTORSlider_2.MajorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4];
            app.TEMPOFACTORSlider_2.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.TEMPOFACTORSlider_2.Value = 0.1;
            app.VIBRATOLabel = uilabel(app.AUDIOEFFECTSPanel);
            app.VIBRATOLabel.Text = 'VIBRATO';
            app.TEMPOLabel = uilabel(app.AUDIOEFFECTSPanel);
            app.TEMPOLabel.Text = 'TEMPO';
            app.APPLYButton = uibutton(app.AUDIOEFFECTSPanel, 'push');
            app.APPLYButton.Text = 'APPLY';
            app.APPLYButton_2 = uibutton(app.AUDIOEFFECTSPanel, 'push');
            app.APPLYButton_2.Text = 'APPLY';
            app.SCALEANDCHORDDETECTIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.SCALEANDCHORDDETECTIONPanel.Title = 'SCALE AND CHORD DETECTION';
            app.CHECKButton = uibutton(app.SCALEANDCHORDDETECTIONPanel, 'push');
            app.CHECKButton.Text = 'CHECK';
            app.SCALEEditFieldLabel = uilabel(app.SCALEANDCHORDDETECTIONPanel);
            app.SCALEEditFieldLabel.Text = 'SCALE';
            app.SCALEEditField = uieditfield(app.SCALEANDCHORDDETECTIONPanel, 'text');
            app.CHORDEditFieldLabel = uilabel(app.SCALEANDCHORDDETECTIONPanel);
            app.CHORDEditFieldLabel.Text = 'CHORD';
            app.CHORDEditField = uieditfield(app.SCALEANDCHORDDETECTIONPanel, 'text');
            app.CHECKButton_2 = uibutton(app.SCALEANDCHORDDETECTIONPanel, 'push');
            app.CHECKButton_2.Text = 'CHECK';
            app.PLOTTYPEDropDownLabel = uilabel(app.PITCHTUNERCORRECTORTab);
            app.PLOTTYPEDropDownLabel.Text = 'PLOT TYPE';
            app.PLOTTYPEDropDown = uidropdown(app.PITCHTUNERCORRECTORTab);
            app.PLOTTYPEDropDown.Items = {'PITCH', 'WAVEFORM'};
            app.PLOTTYPEDropDown.Value = 'PITCH';
            app.PLOTPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.PLOTPanel.Title = 'PLOT ';
            app.ORIGINALButton = uibutton(app.PLOTPanel, 'push');
            app.ORIGINALButton.ButtonPushedFcn = createCallbackFcn(app, @ORIGINALButtonPushed, true);
            app.ORIGINALButton.Text = 'ORIGINAL';
            app.MODIFIEDButton = uibutton(app.PLOTPanel, 'push');
            app.MODIFIEDButton.ButtonPushedFcn = createCallbackFcn(app, @MODIFIEDButtonPushed, true);
            app.MODIFIEDButton.Text = 'MODIFIED';
            app.CLEARButton = uibutton(app.PLOTPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SUPERIMPOSEButton = uibutton(app.PLOTPanel, 'push');
            app.SUPERIMPOSEButton.ButtonPushedFcn = createCallbackFcn(app, @SUPERIMPOSEButtonPushed, true);
            app.SUPERIMPOSEButton.Text = 'SUPERIMPOSE';
            app.EDITINGOPTIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.EDITINGOPTIONPanel.Title = 'EDITING OPTION';
            app.REPLICATEButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.REPLICATEButton.Text = 'REPLICATE';
            app.DELETEButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.DELETEButton.Text = 'DELETE';
            app.JOINButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.JOINButton.Text = 'JOIN';
            app.CUTButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.CUTButton.Text = 'CUT';
            app.PITCHOPERATIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.PITCHOPERATIONPanel.Title = 'PITCH OPERATION';
            app.SHIFTPITCHButton = uibutton(app.PITCHOPERATIONPanel, 'push');
            app.SHIFTPITCHButton.Text = 'SHIFT PITCH';
            app.SELECTAPARTOFPITCHButton = uibutton(app.PITCHOPERATIONPanel, 'push');
            app.SELECTAPARTOFPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTAPARTOFPITCHButtonPushed, true);
            app.SELECTAPARTOFPITCHButton.Text = {'SELECT A '; 'PART OF PITCH'};
            app.GENERATEMIDIFILESANDMUSICSHEETSPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.GENERATEMIDIFILESANDMUSICSHEETSPanel.Title = 'GENERATE MIDI FILES AND MUSIC SHEETS';
            app.BROWSEButton_3 = uibutton(app.GENERATEMIDIFILESANDMUSICSHEETSPanel, 'push');
            app.BROWSEButton_3.Text = 'BROWSE';
            app.EditField_6 = uieditfield(app.GENERATEMIDIFILESANDMUSICSHEETSPanel, 'text');
            app.MIDIFILESButton = uibutton(app.GENERATEMIDIFILESANDMUSICSHEETSPanel, 'push');
            app.MIDIFILESButton.Text = 'MIDI FILES';
            app.MUSICSHEETButton = uibutton(app.GENERATEMIDIFILESANDMUSICSHEETSPanel, 'push');
            app.MUSICSHEETButton.Text = 'MUSIC SHEET';
            app.PRACTICESINGINGTab = uitab(app.TabGroup);
            app.PRACTICESINGINGTab.Title = 'PRACTICE SINGING';
            app.UIAxes1 = uiaxes(app.PRACTICESINGINGTab);
            title(app.UIAxes1, 'PITCH PLOT')
            xlabel(app.UIAxes1, 'TIME')
            ylabel(app.UIAxes1, 'PITCH')
            app.Image2 = uiimage(app.PRACTICESINGINGTab);
            app.Image2.ImageSource = 'practising.jpg';
            app.UPLOADAUDIOTABLEButtonGroup = uibuttongroup(app.PRACTICESINGINGTab);
            app.UPLOADAUDIOTABLEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UPLOADAUDIOTABLEButtonGroupSelectionChanged, true);
            app.UPLOADAUDIOTABLEButtonGroup.Title = 'UPLOAD AUDIO/TABLE';
            app.UPLOADAREFERENCEAUDIOFILEButton = uiradiobutton(app.UPLOADAUDIOTABLEButtonGroup);
            app.UPLOADAREFERENCEAUDIOFILEButton.Text = 'UPLOAD A REFERENCE AUDIO FILE';
            app.UPLOADTABLEWITHPITCHTIMEDETAILSButton = uiradiobutton(app.UPLOADAUDIOTABLEButtonGroup);
            app.UPLOADTABLEWITHPITCHTIMEDETAILSButton.Text = 'UPLOAD TABLE WITH PITCH TIME DETAILS';
            app.UPLOADTABLEWITHPITCHTIMEDETAILSButton.Value = true;
            app.UITable = uitable(app.PRACTICESINGINGTab);
            app.UITable.ColumnName = {'NOTE/PITCH'; 'DURATION'};
            app.UITable.RowName = {};
            app.BROWSEButton_2 = uibutton(app.PRACTICESINGINGTab, 'push');
            app.BROWSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @BROWSEButton_2Pushed, true);
            app.BROWSEButton_2.Enable = 'off';
            app.BROWSEButton_2.Text = 'BROWSE';
            app.EditField_4 = uieditfield(app.PRACTICESINGINGTab, 'text');
            app.EditField_4.Enable = 'off';
            app.SELECTNOTEDropDownLabel = uilabel(app.PRACTICESINGINGTab);
            app.SELECTNOTEDropDownLabel.Text = 'SELECT NOTE';
            app.SELECTNOTEDropDown = uidropdown(app.PRACTICESINGINGTab);
            app.SELECTNOTEDropDown.Items = {'SILENCE', 'C1', 'C#1', 'D1', 'D#1', 'E1', 'F1', 'F#1', 'G1', 'G#1', 'A1', 'A#1', 'B1', 'C2', 'C#2', 'D2', 'D#2', 'E2', 'F2', 'F#2', 'G2', 'G#2', 'A2', 'A#2', 'B2', 'C3', 'C#3', 'D3', 'D#3', 'E3', 'F3', 'F#3', 'G3', 'G#3', 'A3', 'A#3', 'B3', 'C4', 'C#4', 'D4', 'D#4', 'E4', 'F4', 'F#4', 'G4', 'G#4', 'A4', 'A#4', 'B4', 'C5', 'C#5', 'D5', 'D#5', 'E5', 'F5', 'F#5', 'G5', 'G#5', 'A5', 'A#5', 'B5', 'C6', 'C#6', 'D6', 'D#6', 'E6', 'F6', 'F#6', 'G6', 'G#6', 'A6', 'A#6', 'B6'};
            app.SELECTNOTEDropDown.Value = 'SILENCE';
            app.DURATIONinsEditFieldLabel = uilabel(app.PRACTICESINGINGTab);
            app.DURATIONinsEditFieldLabel.Text = 'DURATION(in s)';
            app.DURATIONinsEditField = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.DURATIONinsEditField.Limits = [0 Inf];
            app.UPLOADButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.DONEButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Text = 'DONE';
            app.EditField_5 = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.EditField_5.Limits = [0 20];
            app.OFERRORTHRESHOLDALLOWEDSliderLabel = uilabel(app.PRACTICESINGINGTab);
            app.OFERRORTHRESHOLDALLOWEDSliderLabel.Text = {'% OF ERROR '; 'THRESHOLD'; ' ALLOWED'};
            app.OFERRORTHRESHOLDALLOWEDSlider = uislider(app.PRACTICESINGINGTab);
            app.OFERRORTHRESHOLDALLOWEDSlider.Limits = [0.25 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.MajorTicks = [2 4 6 8 10 12 14 16 18 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.ValueChangingFcn = createCallbackFcn(app, @OFERRORTHRESHOLDALLOWEDSliderValueChanging, true);
            app.OFERRORTHRESHOLDALLOWEDSlider.MinorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6 6.25 6.5 6.75 7 7.25 7.5 7.75 8 8.25 8.5 8.75 9 9.25 9.5 9.75 10 10.25 10.5 10.75 11 11.25 11.5 11.75 12 12.25 12.5 12.75 13 13.25 13.5 13.75 14 14.25 14.5 14.75 15 15.25 15.5 15.75 16 16.25 16.5 16.75 17 17.25 17.5 17.75 18 18.25 18.5 18.75 19 19.25 19.5 19.75 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.Value = 0.25;
            app.RESTARTRERECORDPanel = uipanel(app.PRACTICESINGINGTab);
            app.RESTARTRERECORDPanel.Title = 'RESTART/RERECORD';
            app.RESTARTButton = uibutton(app.RESTARTRERECORDPanel, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RESTART';
            app.RERECORDButton = uibutton(app.RESTARTRERECORDPanel, 'push');
            app.RERECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RERECORDButtonPushed, true);
            app.RERECORDButton.Text = 'RERECORD';
            app.CHECKACCURACYButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.CHECKACCURACYButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKACCURACYButtonPushed, true);
            app.CHECKACCURACYButton.Text = 'CHECK ACCURACY';
            app.ACCURACYISEditFieldLabel = uilabel(app.PRACTICESINGINGTab);
            app.ACCURACYISEditFieldLabel.Text = 'ACCURACY IS';
            app.ACCURACYISEditField = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.PLOTPanel_2 = uipanel(app.PRACTICESINGINGTab);
            app.PLOTPanel_2.Title = 'PLOT ';
            app.REFERENCEButton = uibutton(app.PLOTPanel_2, 'push');
            app.REFERENCEButton.ButtonPushedFcn = createCallbackFcn(app, @REFERENCEButtonPushed, true);
            app.REFERENCEButton.Text = 'REFERENCE';
            app.RECORDEDButton = uibutton(app.PLOTPanel_2, 'push');
            app.RECORDEDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDEDButtonPushed, true);
            app.RECORDEDButton.Text = 'RECORDED';
            app.SUPERIMPOSEButton_2 = uibutton(app.PLOTPanel_2, 'push');
            app.SUPERIMPOSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SUPERIMPOSEButton_2Pushed, true);
            app.SUPERIMPOSEButton_2.Text = 'SUPERIMPOSE';
            app.CLEARButton_2 = uibutton(app.PLOTPanel_2, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.RECORDOPTIONPanel_2 = uipanel(app.PRACTICESINGINGTab);
            app.RECORDOPTIONPanel_2.Title = 'RECORD OPTION';
            app.RECORDButton_2 = uibutton(app.RECORDOPTIONPanel_2, 'push');
            app.RECORDButton_2.ButtonPushedFcn = createCallbackFcn(app, @RECORDButton_2Pushed, true);
            app.RECORDButton_2.Text = 'RECORD';
            app.STOPButton_2 = uibutton(app.RECORDOPTIONPanel_2, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.PLAYOPTIONPanel_2 = uipanel(app.PRACTICESINGINGTab);
            app.PLAYOPTIONPanel_2.Title = 'PLAY OPTION';
            app.RECORDEDButton_2 = uibutton(app.PLAYOPTIONPanel_2, 'push');
            app.RECORDEDButton_2.ButtonPushedFcn = createCallbackFcn(app, @RECORDEDButton_2Pushed, true);
            app.RECORDEDButton_2.Text = 'RECORDED';
            app.REFERENCEButton_2 = uibutton(app.PLAYOPTIONPanel_2, 'push');
            app.REFERENCEButton_2.ButtonPushedFcn = createCallbackFcn(app, @REFERENCEButton_2Pushed, true);
            app.REFERENCEButton_2.Enable = 'off';
            app.REFERENCEButton_2.Text = 'REFERENCE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROJECT_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end