classdef finalapp8thsem14_06_2020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PRACTICESINGINGTab              matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        UPLOADAUDIOTABLEButtonGroup     matlab.ui.container.ButtonGroup
        UPLOADAREFERENCEAUDIOFILEButton  matlab.ui.control.RadioButton
        UPLOADTABLEWITHPITCHTIMEDETAILSButton  matlab.ui.control.RadioButton
        BROWSEButton                    matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        SELECTNOTEDropDownLabel         matlab.ui.control.Label
        SELECTNOTEDropDown              matlab.ui.control.DropDown
        DURATIONinsEditFieldLabel       matlab.ui.control.Label
        DURATIONinsEditField            matlab.ui.control.NumericEditField
        UPLOADButton                    matlab.ui.control.Button
        DONEButton                      matlab.ui.control.Button
        RECORDOPTIONPanel               matlab.ui.container.Panel
        RECORDButton                    matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        PLAYOPTIONPanel                 matlab.ui.container.Panel
        RECORDEDButton                  matlab.ui.control.Button
        REFERENCEButton                 matlab.ui.control.Button
        OFERRORTHRESHOLDALLOWEDLabel    matlab.ui.control.Label
        OFERRORTHRESHOLDALLOWEDSlider   matlab.ui.control.Slider
        EditField_2                     matlab.ui.control.NumericEditField
        ACCURACYISEditFieldLabel        matlab.ui.control.Label
        ACCURACYISEditField             matlab.ui.control.NumericEditField
        CHECKACCURACYButton             matlab.ui.control.Button
        PLOTPanel                       matlab.ui.container.Panel
        REFERENCEButton_2               matlab.ui.control.Button
        RECORDEDButton_2                matlab.ui.control.Button
        SUPERIMPOSEButton               matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        RESTARTRERECORDPanel            matlab.ui.container.Panel
        RESTARTButton                   matlab.ui.control.Button
        RERECORDButton                  matlab.ui.control.Button
        PITCHTUNERCORRECTORTab          matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        PLOTPanel_2                     matlab.ui.container.Panel
        ORIGINALButton                  matlab.ui.control.Button
        MODIFIEDButton                  matlab.ui.control.Button
        SUPERIMPOSEButton_2             matlab.ui.control.Button
        CLEARButton_2                   matlab.ui.control.Button
        EDITINGOPTIONPanel              matlab.ui.container.Panel
        CUTButton                       matlab.ui.control.Button
        REPLICATEButton                 matlab.ui.control.Button
        DELETEButton                    matlab.ui.control.Button
        JOINButton                      matlab.ui.control.Button
        PLOTTYPEDropDownLabel           matlab.ui.control.Label
        PLOTTYPEDropDown                matlab.ui.control.DropDown
        TYPEOFAUDIOTOBEUSEDButtonGroup  matlab.ui.container.ButtonGroup
        RECORDEDAUDIOFILEButton         matlab.ui.control.RadioButton
        RECORDLIVEButton                matlab.ui.control.RadioButton
        BROWSEButton_2                  matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        PLAYOPTIONPanel_2               matlab.ui.container.Panel
        MODIFIEDAUDIOButton             matlab.ui.control.Button
        ORIGINALAUDIOButton_2           matlab.ui.control.Button
        SHIFTNOTEPanel                  matlab.ui.container.Panel
        SHIFTPITCHButton                matlab.ui.control.Button
        SAVEUNDOMODIFICATIONPanel       matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        SAVEButton                      matlab.ui.control.Button
        UNDOButton                      matlab.ui.control.Button
        CHECKButton                     matlab.ui.control.Button
        SCALEEditFieldLabel             matlab.ui.control.Label
        SCALEEditField                  matlab.ui.control.EditField
        NOTESEditFieldLabel             matlab.ui.control.Label
        NOTESEditField                  matlab.ui.control.EditField
        DETECTCHORDSButton              matlab.ui.control.Button
        ADDAUDIOEFFECTSPanel            matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.NumericEditField
        VIBRATOSliderLabel              matlab.ui.control.Label
        VIBRATOSlider                   matlab.ui.control.Slider
        APPLYButton_3                   matlab.ui.control.Button
        EditField_7                     matlab.ui.control.NumericEditField
        TEMPOFACTORLabel                matlab.ui.control.Label
        TEMPOFACTORSlider               matlab.ui.control.Slider
        APPLYButton                     matlab.ui.control.Button
        RECORDOPTIONPanel_2             matlab.ui.container.Panel
        RECORDButton_2                  matlab.ui.control.Button
        STOPButton_2                    matlab.ui.control.Button
        SELECTAPARTOFPITCHButton        matlab.ui.control.Button
        DETECTKEYButton                 matlab.ui.control.Button
        KEYEditFieldLabel               matlab.ui.control.Label
        KEYEditField                    matlab.ui.control.EditField
        GENERATEMIDIFILEButton          matlab.ui.control.Button
        MUSICSHEETPanel                 matlab.ui.container.Panel
        EditField_8                     matlab.ui.control.EditField
        BROWSEButton_3                  matlab.ui.control.Button
        DISPLAYMUSICSHEETButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
            file
            path
            freqref         %referencefrequency or referencepitch matrix
            timeref         %reference time matrix
            xtref
            timeact         %recorded time matrix
            freqact         %recorded frequency matrix
            xtact
            totalduration   %total duration of the reference audio
            Fs              %sampling frequency
            originalxt      %time domain matrix
            originaltime    %time matrix
            freq            %frequency ,atrix
            t
            modifiedxt      %modified audio matrix
            modifiedtime    %modified time matrix
            modifiedfreq    %modified frequency matrix
            music_note_frequency = py.dict(pyargs('SILENCE',0,'C0',16.35,'C#0',17.32,'D0',18.35,'D#0',19.45,'E0',20.6,'F0',21.83,'F#0',23.12,'G0',24.50,'G#0',25.96,'A0',27.50,'A#0',29.14,'B0',30.87,'C1',32.7,'C#1',34.65,'D1',36.71,'D#1',38.89,'E1',41.2,'F1',46.25,'F#1',46.25,'G1',49,'G#1',51.91,'A1',55,'A#1',58.27,'B1',61.74,'C2',65.41,'C#2',69.30,'D2',73.42,'D#2',77.78,'E2',82.41,'F2',87.31,'F#2',92.5,'G2',98,'G#2',103.83,'A2',110,'A#2',116.54,'B2',123.47,'C3',130.81,'C#3',138.59,'D3',146.83,'D#3',155.56,'E3',164.81,'F3',174.61,'F#3',185,'G3',196,'G#3',207.65,'A3',220,'A#3',233.08,'B3',246.94,'C4',261.63,'C#4',277.18,'D4',293.66,'D#4',311.13,'E4',329.63,'F4',349.23,'F#4',370,'G4',392,'G#4',415.30,'A4',440,'A#4',466.16,'B4',493.88,'C5',523.25,'C#5',554.37,'D5',587.33,'D#5',622.25,'E5',659.25,'F5',698.46,'F#5',740,'G5',784,'G#5',830.61,'A5',880,'A#5',932.33,'B5',987.77,'C6',1046.5,'C#6',1108.73,'D6',1174.67,'D#6',1244.51,'E6',1318.51,'F6',1396.91,'F#6',1480.0,'G6',1568,'G#6',1661.22,'A6',1760,'A#6',1864.67,'B6',1975.53));
            %list of frequency and its note
            music_scale = py.dict(pyargs('C Major',py.list({'C','D','E','F','G','A','B'}),'C# Major',py.list({'C#','D#','F','F#','G#','A#','C'}),'D Major',py.list({'D','E','F#','G','A','B','C#'}),'D# Major',py.list({'D#','F','G','G#','A','C','D'}),'E Major',py.list({'E','F#','G#','A','B','C#','D#'}),'F Major',py.list({'F','G','A','A#','C','D','E'}),'F# Major',py.list({'F#','G#','A#','B','C#','D#','F'}),'G Major',py.list({'G','A','B','C','D','E','F#'}),'G# Major',py.list({'G#','A#','C','C#','D#','F','G'}),'A Major',py.list({'A','B','C#','D','E','F#','G#'}),'A# Major',py.list({'A#','C','D','D#','F','G','A'}),'B Major',py.list({'B','C#','D#','E','F#','G#','A#'}),'E Major',py.list({'E','F#','G#','A','B','C#','D#'}),'C Minor',py.list({'C','D','D#','F','G','G#','A#'}),'C# Minor',py.list({'C#','D#','E','F#','G#','A','B'}),'D Minor',py.list({'D','E','F','G','A','A#','C#'}),'D# Minor',py.list({'D#','F','F#','G#','A#','B','C#'}),'E Minor',py.list({'E','F#','G','A','B','C','D'}),'F Minor',py.list({'F','G','G#','A#','C','C#','D#'}),'F# Minor',py.list({'F#','G#','A','B','C#','D','E'}),'G Minor',py.list({'G','A','A#','C','D','D#','F'}),'G# Minor',py.list({'G#','A#','B','C#','D#','E','F#'}),'A Minor',py.list({'A','B','C','D','E','F','G'}),'A# Minor',py.list({'A#','C','C#','D#','F','F#','G#'}),'B Minor',py.list({'B','C#','D','E','F#','G','A'}),'C Major Pentatonic',py.list({'C','D','E','G','A'}),'C# Major Pentatonic',py.list({'C#','D#','F','G#','A#'}),'D Major Pentatonic',py.list({'D','E','F#','A','B'}),'D# Major Pentatonic',py.list({'D#','F','G','A#','C'}),'E Major Pentatonic',py.list({'E','F#','G#','B','C#'}),'F Major Pentatonic',py.list({'F','G','A','C','D'}),'F# Major Pentatonic',py.list({'F#','G#','A#','C#','D#'}),'G Major Pentatonic',py.list({'G','A','B','D','E'}),'G# Major Pentatonic',py.list({'G#','A#','C','D#','F'}),'A Major Pentatonic',py.list({'A','B','C#','E','F#'}),'A# Major Pentatonic',py.list({'A#','C','D','F','G'}),'B Major Pentatonic',py.list({'B','C#','D#','F#','G#'}),'C Minor Pentatonic',py.list({'C','D#','F','G','A#'}),'C# Minor Pentatonic',py.list({'C#','E','F#','G#','B'}),'D Minor Pentatonic',py.list({'D','F','G','A','C'}),'D# Minor Pentatonic',py.list({'D#','F#','G#','A#','C#'}),'E Minor Pentatonic',py.list({'E','G','A','B','D'}),'F Minor Pentatonic',py.list({'F','G#','A#','C','D#'}),'F# Minor Pentatonic',py.list({'F#','A','B','C#','E'}),'G Minor Pentatonic',py.list({'G','A#','C','D','F'}),'G# Minor Pentatonic',py.list({'G#','B','C#','D#','F#'}),'A Minor Pentatonic',py.list({'A','C','D','E','G'}),'A# Minor Pentatonic',py.list({'A#','C#','D#','F','G#'}),'B Minor Pentatonic',py.list({'B','D','E','F#','A'})));
            %list of scales
            musical_note = py.list({'A','A#','B','C','C#','D','D#','E','F','F#','G','G#'});
            %list of all notes
            isrecording
            Frequency
            startindex
            endindex
            xt
            segmentedxt
            remainingxt;
            ChangeData
            ChangeTempoData
            music_sheet
    end
    
    methods (Access = private)
        
        function xb = recordpractice(app)
            if app.isrecording       
                app.Fs = 44100;
                a = audiorecorder(app.Fs,16,1);
                b = audiorecorder(app.Fs,16,1);
                record(b);
                app.xtact = [];
                app.Frequency = [];
                count = 0;  
                while(1)
                    recordblocking(a,0.5);
                    x = getaudiodata(a);
                    app.xtact = [app.xtact; x];
                    [fre,~] = YinAlgo(x,app.Fs);
                    app.Frequency = [app.Frequency fre];
                    hold(app.UIAxes1,'on');
                    plot(app.UIAxes1,linspace(0.5*count,0.5*(count+1),length(fre)),fre,"LineWidth",1,"Color",[0 0 1]);
                    hold(app.UIAxes1,'on');
                    plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1,"Color",[0.6 0 0.3]);
                    count = count + 1;
                    if (app.isrecording == false)
                        stop(b);
                        xb = getaudiodata(b);
                        app.t = (0 : length(xb)-1)/app.Fs;
                        break;
                    end
                end
            end
        end


        function xb = recording(app)
            if app.isrecording       
                app.Fs = 44100;
                a = audiorecorder(app.Fs,16,1);
                b = audiorecorder(app.Fs,16,1);
                record(b);
                app.xt = [];
                app.Frequency = [];
                count = 0;  
                while(1)
                    recordblocking(a,0.5);
                    x = getaudiodata(a);
                    app.xt = [app.xt; x];
                    [fre,~] = YinAlgo(x,app.Fs);
                    app.Frequency = [app.Frequency fre];
                    hold(app.UIAxes2,'on');
                    if (strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                        plot(app.UIAxes2,linspace(0.5*count,0.5*(count+1),length(fre)),fre,"LineWidth",1,"Color",[0 0 1]);
                    elseif (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                        plot(app.UIAxes2,linspace(0.5*count,0.5*(count+1),length(x)),x,"Color",[0 0 1]);
                    end
                    count = count + 1;
                    if (app.isrecording == false)
                        stop(b);
                        xb = getaudiodata(b);
                        app.t = (0 : length(xb)-1)/app.Fs;
                        break;
                    end
                end
            end
        end
        
        % The function that implements the click and
        % drag of the pitch plot
        function func(app)
            % Plot the sample graph
    %             t = linspace(0, 10, 1000);
    %             x = 5 * t;
            % [app.xt, app.Fs] = audioread('../../piano.wav');
            % t = linspace(0, length(xt)/Fs, length(xt));
    %             ylim(app.UIAxes, [0 1000])
            % [x, t] = yinAlgo(xt, Fs);
            plotHandle = plot(app.UIAxes2, app.originaltime, app.freq);
            disp(plotHandle);
            xAxesPos = 0;
            yAxesPos = 0;
            xIndex = 0;
            IsMouseDown = false;
            xIterIdxLower = 0;
            xIterIdxUpper = 0;
            xData = 0;
            yData = 0;
            app.ChangeData = [];
        
            % Set the callback functions for the figure
            set(app.UIFigure, ...
                'WindowButtonDownFcn',   @mouseDownCallback, ...
                'WindowButtonUpFcn',     @mouseUpCallback,   ...
                'WindowButtonMotionFcn', @mouseMotionCallback);
        
            % This callback is executed when mouse is held down
            function mouseDownCallback(clickHandle, varargin)
                currentPointWrtAxes = get(app.UIAxes2, 'CurrentPoint');
                xAxesPos            = currentPointWrtAxes(1,1);
                yAxesPos            = currentPointWrtAxes(1,2);
                disp('X Coordinate of click wrt Axes'); disp(xAxesPos);
                disp('Y Coordinate of click wrt Axes'); disp(yAxesPos);
                % Find relative index
                xIndex = round(xAxesPos * length(app.freq) / app.originaltime(end));
                xIterIdxLower = xIndex;
                xIterIdxUpper = xIndex;
                while(true)
                    xPitchDiffLower = abs(app.freq(xIterIdxLower) - app.freq(xIndex));
                    if (xPitchDiffLower > 20 || xIterIdxLower == 1)
                        break;
                    end
                    xIterIdxLower = xIterIdxLower - 1;
                end
                while(true)
                    xPitchDiffUpper = abs(app.freq(xIterIdxUpper) - app.freq(xIndex));
                    if (xPitchDiffUpper > 20 || xIterIdxUpper == length(app.freq))
                        break;
                    end
                    xIterIdxUpper = xIterIdxUpper + 1;
                end
                IsMouseDown = true;
                clickHandle.Pointer = 'hand';
            end
        
            % This callback is executed when mouse is released
            function mouseUpCallback(clickHandle, varargin)
                IsMouseDown = false;
                clickHandle.Pointer = 'arrow';
                xIterIdxLower = xIterIdxLower+1;
                xIterIdxUpper = xIterIdxUpper-1;
                app.ChangeData = [app.ChangeData ...
                    app.originaltime(xIterIdxLower)-0.1 1 ...    
                    app.originaltime(xIterIdxLower) yData(xIterIdxLower)/app.freq(xIterIdxLower) ...
                    app.originaltime(xIterIdxUpper) yData(xIterIdxUpper)/app.freq(xIterIdxUpper) ...
                    app.originaltime(xIterIdxUpper)+0.1 1];
                disp(app.ChangeData);
            end
        
            % This callback is executed when mouse is moved
            function mouseMotionCallback(~, varargin)
                if (IsMouseDown == true)
                    currentPoint = get(app.UIAxes2, 'CurrentPoint');
                    yCoord       = currentPoint(2,2);
                    % compute the displacement from previous position to current
            %         xDiff = xCoord - xAxesPos;
                    yDiff = yCoord - yAxesPos;
                    % adjust this for the data corresponding to movingPlotHndle
                    xData = get(plotHandle,'XData');                    
                    yData = get(plotHandle,'YData');
                    yData(xIterIdxLower: xIterIdxUpper) = yData(xIterIdxLower: xIterIdxUpper) + yDiff;
                    set(plotHandle,'YData',yData,'XData',xData);
                    yAxesPos = yCoord;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: OFERRORTHRESHOLDALLOWEDSlider
        function OFERRORTHRESHOLDALLOWEDSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
        end

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)
            app.isrecording = true;
            if ~(isempty(app.freqref))
                cla(app.UIAxes1)
                f = app.UIFigure;
                d = uiprogressdlg(f, "Title", "RECORD AUDIO", "Message", "Please Wait");
                pause(0.5);
                d.Value = 0.33;
                d.Message = "RECORDING STARTS IN 3";
                pause(1);
                d.Value = 0.66;
                d.Message = "RECORDING STARTS IN 2";
                pause(1);
                d.Value = 0.99;
                d.Message = "RECORDING STARTS IN 1";
                pause(1);
                d.Value = 1;
                d.Message = "START";
                pause(0.5);
                close(d);
                cla(app.UIAxes2)
                
                app.RECORDButton.Enable = 'off';
                app.STOPButton.Enable = 'on';
                app.RECORDEDButton.Enable = 'off';
                app.REFERENCEButton.Enable = 'off';
                app.CHECKACCURACYButton.Enable = 'off';
                app.REFERENCEButton_2.Enable = 'off';
                app.RECORDEDButton_2.Enable = 'off';
                app.SUPERIMPOSEButton.Enable = 'off';
                app.CLEARButton.Enable = 'off';
                app.RESTARTButton.Enable = 'off';
                app.RERECORDButton.Enable = 'off';
                
                app.xtact = recordpractice(app);
                app.xtact = app.xtact(1:length(app.freqref));
                
                app.RECORDButton.Enable = 'on';
                app.STOPButton.Enable = 'off';
                app.RECORDEDButton.Enable = 'on';
                app.REFERENCEButton.Enable = 'on';
                app.CHECKACCURACYButton.Enable = 'on';
                app.REFERENCEButton_2.Enable = 'on';
                app.RECORDEDButton_2.Enable = 'on';
                app.SUPERIMPOSEButton.Enable = 'on';
                app.CLEARButton.Enable = 'on';
                app.RESTARTButton.Enable = 'on';
                app.RERECORDButton.Enable = 'on';
                
                app.timeact = (0 : length(app.xtact)-1)/app.Fs;
                [app.freqact, app.timeact] = YinAlgo(app.xtact,app.Fs);
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE PROVIDE A REFERENCE FILE!!", "REFERENCE FILE NOT FOUND", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.isrecording = false;
        end

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
            Note = app.SELECTNOTEDropDown.Value;
            Time = app.DURATIONinsEditField.Value;
            newrow = {Note Time};
            app.UITable.Data = [app.UITable.Data ; newrow];
        end

        % Button pushed function: DONEButton
        function DONEButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "NO FURTHUR APPENDING TO TABLE WILL BE ALLOWED","STOP APPENDING TO TABLE??", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                app.freqref = [];  
                app.Fs = 44100;
                app.totalduration = 0;
                for j = 1 : size(app.UITable.Data, 1)
                    app.freqref = [app.freqref app.music_note_frequency{app.UITable.Data{j, 1}}*ones(1, app.Fs*app.UITable.Data{j, 2})];
                    app.totalduration = app.totalduration + app.Fs*app.UITable.Data{j, 2} ;
                end
                app.timeref = linspace(0,length(app.freqref)/app.Fs,length(app.freqref));
            end
        end

        % Button pushed function: CHECKACCURACYButton
        function CHECKACCURACYButtonPushed(app, event)
            start = 1;
            crt_frame = 0;
            no_frame = 0;
            win_len = 2048;
            app.OFERRORTHRESHOLDALLOWEDSlider.Value
            while start < length(app.freqref)
                freq_dev = app.freqref(start) * app.OFERRORTHRESHOLDALLOWEDSlider.Value/100;
                max_freq = app.freqref(start) + freq_dev;
                min_freq = app.freqref(start) - freq_dev;
                if (min_freq <=app.freqact(start)) && (app.freqact(start) <= max_freq)
                    crt_frame = crt_frame + 1;
                end
                no_frame = no_frame + 1;
                start = start + win_len;
            end
            f = app.UIFigure;
                d = uiprogressdlg(f, "Title", "CHECK ACCURACY", "Message", "Please Wait");
                pause(0.5);
                d.Value = 0.33;
                d.Message = "COLLECTING REFERENCE AUDIO";
                pause(1);
                d.Value = 0.66;
                d.Message = "COLLECTING RECORDED AUDIO";
                pause(1);
                d.Value = 0.99;
                d.Message = "COMPARING REFERENCE AND RECORDED AUDIO";
                pause(1);
                d.Value = 1;
                d.Message = "ACCURACY IS";
                pause(1);
                close(d);
             app.ACCURACYISEditField.Value = crt_frame/no_frame*100;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes1)
        end

        % Button pushed function: REFERENCEButton_2
        function REFERENCEButton_2Pushed(app, event)
            cla(app.UIAxes1)    
            plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1,"Color",[0.6 0 0.3])
        end

        % Button pushed function: RECORDEDButton_2
        function RECORDEDButton_2Pushed(app, event)
            cla(app.UIAxes1)
            plot(app.UIAxes1,app.timeact,app.freqact,"LineWidth",1,"Color",[0 0 1]);
        end

        % Button pushed function: SUPERIMPOSEButton
        function SUPERIMPOSEButtonPushed(app, event)
            cla(app.UIAxes1)    
            plot(app.UIAxes1,app.timeref,app.freqref,"LineWidth",1,"Color",[0.6 0 0.3]);
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.timeact,app.freqact,"LineWidth",1,"Color",[0 0 1]);
            hold(app.UIAxes1,'off');
        end

        % Button pushed function: RERECORDButton
        function RERECORDButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU WANT TO RERECORD ?", "RECORDED AUDIO WILL BE ERASED", "Icon", "warning");
            disp(selection);
            if selection == "OK"    
                clear app.freqact,app.xtact;
            end
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU YOU WANT TO RESTART EVERYTHING ?","RECORDED AUDIO AND TABLE WILL BE ERASED", "Icon", "warning");
            disp(selection);
            if selection == "OK"     
            app.UITable.Data = {};
            cla(app.UIAxes1);
            app.freqact = [];
            app.xtact = [];
            app.freqref = [];
            app.timeact = [];
            app.timeref = [];
            end
        end

        % Button pushed function: BROWSEButton
        function BROWSEButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            app.EditField.Value = app.file;
            disp([app.path app.file]);
            string([app.path app.file])
            [app.xtref, app.Fs] = audioread(string([app.path app.file]));
            app.xtref = app.xtref(:,1);
            [app.freqref,app.timeref] = YinAlgo(app.xtref,app.Fs);          
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            delay = app.DELAYinmsSlider.Value;
            modfreq = app.VIBRATOSlider.Value/1000;
            app.segmentedxt = app.modifiedxt(app.startindex + 1 :app.endindex );
            enhancext = Vibrato(app.segmentedxt,app.Fs,modfreq,delay);
            app.modifiedxt = [app.modifiedxt(1:app.startindex - 1); enhancext ; app.modifiedxt(app.startindex :end)];
        end

        % Value changing function: VIBRATOSlider
        function VIBRATOSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value = changingValue;
        end

        % Callback function
        function DELAYinmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = changingValue;
        end

        % Callback function
        function COMPRESSEXPANDRATIOSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value = changingValue;
        end

        % Value changing function: TEMPOFACTORSlider
        function TEMPOFACTORSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_7.Value = changingValue;
        end

        % Button pushed function: RECORDButton_2
        function RECORDButton_2Pushed(app, event)
            app.isrecording = true;
                cla(app.UIAxes2)
                f = app.UIFigure;
                d = uiprogressdlg(f, "Title", "RECORD AUDIO", "Message", "Please Wait");
                pause(0.5);
                d.Value = 0.33;
                d.Message = "RECORDING STARTS IN 3";
                pause(1);
                d.Value = 0.66;
                d.Message = "RECORDING STARTS IN 2";
                pause(1);
                d.Value = 0.99;
                d.Message = "RECORDING STARTS IN 1";
                pause(1);
                d.Value = 1;
                d.Message = "START";
                pause(0.5);
                close(d);
                cla(app.UIAxes2)
                
                app.RECORDButton_2.Enable = 'off';
                app.STOPButton_2.Enable = 'on';
                app.SELECTAPARTOFPITCHButton.Enable = 'off';
                app.ORIGINALButton.Enable = 'off';
                app.MODIFIEDButton.Enable = 'off';
                app.SUPERIMPOSEButton_2.Enable = 'off';
                app.CLEARButton_2.Enable = 'off';
                app.CUTButton.Enable = 'off';
                app.REPLICATEButton.Enable = 'off';
                app.DELETEButton.Enable = 'off';
                app.JOINButton.Enable = 'off';
                app.MODIFIEDAUDIOButton.Enable = 'off';
                app.ORIGINALAUDIOButton_2.Enable = 'off';
                app.UpShiftButton.Enable = 'off';
                app.DownShiftButton.Enable = 'off';
                app.SAVEButton.Enable = 'off';
                app.UNDOButton.Enable = 'off';
                app.CHECKButton.Enable = 'off';
                app.DETECTCHORDSButton.Enable = 'off';
                app.APPLYButton.Enable = 'off';
                app.APPLYButton_3.Enable = 'off';
                
                app.originalxt = recording(app);
                app.originalxt = app.originalxt(:,1);
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                [app.freq, app.originaltime] = YinAlgo(app.originalxt,app.Fs);
                
                app.RECORDButton_2.Enable = 'on';
                app.STOPButton_2.Enable = 'off';
                app.SELECTAPARTOFPITCHButton.Enable = 'on';
                app.ORIGINALButton.Enable = 'on';
                app.MODIFIEDButton.Enable = 'on';
                app.SUPERIMPOSEButton_2.Enable = 'on';
                app.CLEARButton_2.Enable = 'on';
                app.CUTButton.Enable = 'on';
                app.REPLICATEButton.Enable = 'on';
                app.DELETEButton.Enable = 'on';
                app.JOINButton.Enable = 'on';
                app.MODIFIEDAUDIOButton.Enable = 'on';
                app.ORIGINALAUDIOButton_2.Enable = 'on';
                app.UpShiftButton.Enable = 'on';
                app.DownShiftButton.Enable = 'on';
                app.SAVEButton.Enable = 'on';
                app.UNDOButton.Enable = 'on';
                app.CHECKButton.Enable = 'on';
                app.DETECTCHORDSButton.Enable = 'on';
                app.APPLYButton.Enable = 'on';
                app.APPLYButton_3.Enable = 'on';
                
                app.modifiedxt = app.originalxt;
                app.modifiedfreq = app.freq;
                app.modifiedtime = app.originaltime;
                
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
            app.isrecording = false;
        end

        % Button pushed function: ORIGINALButton
        function ORIGINALButtonPushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes2)
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.originalxt,"Color",[0.6 0 0.3]);
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes2)   
                app.originaltime = (0 : length(app.freq)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.freq,"LineWidth",1,"Color",[0.6 0 0.3])
            end
        end

        % Button pushed function: MODIFIEDButton
        function MODIFIEDButtonPushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes2)
                app.modifiedtime = (0 : length(app.modifiedxt)-1)/app.Fs;
                plot(app.UIAxes2,app.modifiedtime,app.modifiedxt,"Color",[0 0 1]);
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes2) 
                app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
                plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
            end
        end

        % Button pushed function: BROWSEButton_2
        function BROWSEButton_2Pushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            app.EditField_3.Value = app.file;
            disp([app.path app.file]);
            string([app.path app.file])
            [app.originalxt, app.Fs] = audioread(string([app.path app.file]));
             app.originalxt = app.originalxt(:,1);
            app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
            [app.freq, app.originaltime] = YinAlgo(app.originalxt,app.Fs);
            app.modifiedxt = app.originalxt;
            app.modifiedfreq = app.freq;
            app.modifiedtime = app.originaltime;
           
        end

        % Button pushed function: REFERENCEButton
        function REFERENCEButtonPushed(app, event)
           if ~(isempty(app.freqref))
                cla(app.UIAxes1)
                plot(app.UIAxes1,app.timeref,app.freqref)
                end_time = length(app.freqref)/app.Fs;
                h=line(app.UIAxes1,[0,0],[0,max(app.freqref)+50],'color','r');
                sound(app.xtref, app.Fs) 
                tic 
                z=toc; 
                while z<end_time
                   set(h, 'xdata', z*[1,1]) 
                   drawnow 
                   z=toc; 
                end
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE PROVIDE A REFERENCE FILE !!", "REFERENCE NOT FOUND ", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: RECORDEDButton
        function RECORDEDButtonPushed(app, event)
        
            if ~(isempty(app.freqact))        
                cla(app.UIAxes1)
                plot(app.UIAxes1,app.timeact,app.freqact)
                end_time = length(app.freqref)/app.Fs;
                h=line(app.UIAxes1,[0,0],[0,max(app.freqact)+50],'color','r');
                sound(app.xtact, app.Fs) 
                tic 
                z=toc; 
                while z<end_time
                   set(h, 'xdata', z*[1,1]) 
                   drawnow 
                   z=toc; 
                end
            else
                f = app.UIFigure;
                uiconfirm(f, "PLEASE RECORD AN AUDIO !!", "RECORDING NOT FOUND", "Icon", "error","Options",{'OK'});
            end
        end

        % Button pushed function: ORIGINALAUDIOButton_2
        function ORIGINALAUDIOButton_2Pushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes2)
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.originalxt,"Color",[0.6 0 0.3]);
                end_time = length(app.originalxt)/app.Fs;
                h=line(app.UIAxes2,[0,0],[-1,1],'color','r');
                sound(app.originalxt, app.Fs) 
                tic 
                z=toc; 
                while z<end_time
                   set(h, 'xdata', z*[1,1])
                   drawnow 
                   z=toc; 
                end
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes2)   
                app.originaltime = (0 : length(app.freq)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.freq,"LineWidth",1,"Color",[0.6 0 0.3])
                end_time = length(app.freq)/app.Fs;
                h=line(app.UIAxes2,[0,0],[0,max(app.freq)+50],'color','r');
                sound(app.originalxt, app.Fs) 
                tic 
                z=toc; 
                while z<end_time
                   set(h, 'xdata', z*[1,1])
                   drawnow 
                   z=toc; 
                end
            end
        end

        % Button pushed function: MODIFIEDAUDIOButton
        function MODIFIEDAUDIOButtonPushed(app, event)
            if (isempty(app.ChangeData) && isempty(app.ChangeTempoData))
                if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                    cla(app.UIAxes2)
                    app.modifiedtime = (0 : length(app.modifiedxt)-1)/app.Fs;
                    plot(app.UIAxes2,app.modifiedtime,app.modifiedxt,"Color",[0 0 1]);
                    end_time = length(app.originalxt)/app.Fs;
                    h=line(app.UIAxes2,[0,0],[-1,1],'color','r');
                    sound(app.modifiedxt, app.Fs) 
                    tic 
                    z=toc; 
                    while z<end_time
                       set(h, 'xdata', z*[1,1])
                       drawnow 
                       z=toc; 
                    end
                elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                    cla(app.UIAxes2) 
                    app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
                    plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
                    
                    end_time = length(app.modifiedfreq)/app.Fs;
                    h=line(app.UIAxes2,[0,0],[0,max(app.modifiedfreq)+50],'color','r');
                    sound(app.modifiedxt, app.Fs) 
                    tic 
                    z=toc; 
                    while z<end_time
                       set(h, 'xdata', z*[1,1])
                       drawnow 
                       z=toc; 
                    end
                end
            elseif (isempty(app.ChangeData))
                disp('Changed tempo data');
                disp(app.ChangeTempoData);  
                disp([app.path app.file]);
                [~, fs] = modifyPitch([app.path app.file], [0, 1, 1, 1], app.ChangeTempoData);
                disp(fs);
                [~, fileName, ~] = fileparts(app.file);
                audioPath = ['../outputSounds/' fileName '_harmonicModelTransformation.wav'];
                disp(audioPath);
                [modifiedAudio, fs] = audioread(audioPath);
                soundsc(modifiedAudio, fs);
            elseif (isempty(app.ChangeTempoData))
                disp('Changed pitch data');
                disp(app.ChangeData);  
                disp([app.path app.file]);
                [~, fs] = modifyPitch([app.path app.file], app.ChangeData, [0, 0, 1, 1]);
                disp(fs);
                [~, fileName, ~] = fileparts(app.file);
                audioPath = ['../outputSounds/' fileName '_harmonicModelTransformation.wav'];
                disp(audioPath);
                [modifiedAudio, fs] = audioread(audioPath);
                soundsc(modifiedAudio, fs);
            else
                disp('Changed both');
                disp(app.ChangeData);
                disp(app.ChangeTempoData);
                disp([app.path app.file]);
                [~, fs] = modifyPitch([app.path app.file], app.ChangeData, app.ChangeTempoData);
                disp(fs);
                [~, fileName, ~] = fileparts(app.file);
                audioPath = ['../outputSounds/' fileName '_harmonicModelTransformation.wav'];
                disp(audioPath);
                [modifiedAudio, fs] = audioread(audioPath);
                soundsc(modifiedAudio, fs);
            end
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            cla(app.UIAxes2);
        end

        % Button pushed function: SUPERIMPOSEButton_2
        function SUPERIMPOSEButton_2Pushed(app, event)
            if (strcmp(app.PLOTTYPEDropDown.Value,'WAVEFORM'))
                cla(app.UIAxes2)
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.originalxt,"Color",[0.6 0 0.3]);
                hold(app.UIAxes2,'on')
                app.modifiedtime = (0 : length(app.modifiedxt)-1)/app.Fs;
                plot(app.UIAxes2,app.modifiedtime,app.modifiedxt,"Color",[0 0 1]);
                hold(app.UIAxes2,'off')
            elseif(strcmp(app.PLOTTYPEDropDown.Value,'PITCH'))
                cla(app.UIAxes2)   
                app.originaltime = (0 : length(app.freq)-1)/app.Fs;
                plot(app.UIAxes2,app.originaltime,app.freq,"LineWidth",1,"Color",[0.6 0 0.3])
                hold(app.UIAxes2,'on')
                app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
                plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
                hold(app.UIAxes2,'off')
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU WANT TO SAVE?", "CONFIRM SAVE", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                app.originalxt = app.modifiedxt;
                app.originaltime = (0 : length(app.originalxt)-1)/app.Fs;
                audiowrite('Modified_Sound.wav',app.modifiedxt,app.Fs);
            end
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f, "ARE YOU SURE YOU WANT TO UNDO ALL CHANGES?", "CONFIRM UNDO", "Icon", "warning");
            disp(selection);
            if selection == "OK"
                app.modifiedxt = app.originalxt;
                app.modifiedxt = (0 : length(app.modifiedxt)-1)/app.Fs;
            end
        end

        % Button pushed function: CHECKButton
        function CHECKButtonPushed(app, event)
            [detectedscale,detectednotes] = scale_detection(app.modifiedxt,app.Fs);
            app.SCALEEditField.Value    = detectedscale;
            app.NOTESEditField.Value = detectednotes;
        end

        % Button pushed function: SELECTAPARTOFPITCHButton
        function SELECTAPARTOFPITCHButtonPushed(app, event)
            cla(app.UIAxes2) 
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
            [x,~] = selectDatapoints(app.UIAxes2);
            app.startindex = round(x(1)*app.Fs);
            app.endindex = round(x(end)*app.Fs);
        
            function [x,y] = selectDatapoints(ax)
                % Remove any pre-existing rectanlges (if any)
                delete(findall(ax, 'Type',  'images.roi.Rectangle'))
                
                % Get coordinates of all data points in the axes
                xyobj = findall(ax.Children, '-Property','xData');
                xydata = get(xyobj, {'XData','YData'});
                xydataMat = cell2mat(xydata');
                
               
                pan(app.UIAxes2, 'off') %turn off panning so the interaction doesn't drag the data.
                roi = drawrectangle(ax);
                
                % quit if figure is closed
                if ~isvalid(roi)
                    x = [];
                    y = [];
                    return
                end
                
                % determine which coordinates are within the ROI
                isIn = xydataMat(1,:) >= roi.Position(1) & xydataMat(1,:) <= sum(roi.Position([1,3])) ...
                    & xydataMat(2,:) >= roi.Position(2) & xydataMat(2,:) <= sum(roi.Position([2,4]));
                
                % Delete ROI
                delete(roi)
                
                % Return outputs
                x = xydataMat(1,isIn);
                y = xydataMat(2,isIn);
                disp([ x(1),y(1)]);
                disp([x(end),y(end)]);
            end
        end

        % Button pushed function: CUTButton
        function CUTButtonPushed(app, event)
         
            app.segmentedxt = app.modifiedxt(app.startindex + 1 :app.endindex );
            app.modifiedxt = [app.modifiedxt(1:app.startindex) ;app.modifiedxt(app.endindex + 1 : end)];
            [app.modifiedfreq , app.modifiedtime] = YinAlgo(app.modifiedxt,app.Fs);
            cla(app.UIAxes2) 
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            %app.segmentedxt = app.modifiedxt(app.startindex + 1 : app.endindex );
            app.modifiedxt = [app.modifiedxt(1:app.startindex) ;app.modifiedxt(app.endindex + 1 : end)];
            [app.modifiedfreq , app.modifiedtime] = YinAlgo(app.modifiedxt,app.Fs);
            cla(app.UIAxes2) 
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
        end

        % Button pushed function: REPLICATEButton
        function REPLICATEButtonPushed(app, event)
            app.segmentedxt = app.modifiedxt(app.startindex + 1 :app.endindex );
            %              app.remainingaudio = app.xt;
            [app.modifiedfreq , app.modifiedtime] = YinAlgo(app.modifiedxt,app.Fs);
            cla(app.UIAxes2)
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
        end

        % Button pushed function: JOINButton
        function JOINButtonPushed(app, event)
            app.modifiedxt = [app.modifiedxt(1:app.startindex - 1); app.segmentedxt ; app.modifiedxt(app.startindex :end)];
            [app.modifiedfreq , app.modifiedtime] = YinAlgo(app.modifiedxt,app.Fs);
            cla(app.UIAxes2)
            app.modifiedtime = (0 : length(app.modifiedfreq)-1)/app.Fs;
            plot(app.UIAxes2,app.modifiedtime,app.modifiedfreq,"LineWidth",1,"Color",[0 0 1]);
        end

        % Button pushed function: APPLYButton_3
        function APPLYButton_3Pushed(app, event)
            offsetValue = (app.endindex - app.startindex)/(app.Fs*app.TEMPOFACTORSlider.Value);
            disp(app.startindex);
            disp(app.endindex);
            disp(offsetValue);
            if (app.TEMPOFACTORSlider.Value > 1)
                offsetValue = -1*offsetValue;
            end
            app.ChangeTempoData = [0 0 app.startindex/app.Fs app.startindex/app.Fs app.endindex/app.Fs app.endindex/app.Fs+offsetValue];
            disp(app.ChangeTempoData);
        end

        % Selection changed function: UPLOADAUDIOTABLEButtonGroup
        function UPLOADAUDIOTABLEButtonGroupSelectionChanged(app, event)
            %selectedButton = app.UPLOADAUDIOTABLEButtonGroup.SelectedObject;
            if app.UPLOADAREFERENCEAUDIOFILEButton.Value
                app.BROWSEButton.Enable = 'on';
                app.UPLOADButton.Enable = 'off';
                app.DONEButton.Enable = 'off';
                app.REFERENCEButton.Enable = 'off';
            elseif app.UPLOADTABLEWITHPITCHTIMEDETAILSButton.Value
                app.BROWSEButton.Enable = 'off';
                app.UPLOADButton.Enable = 'on';
                app.DONEButton.Enable = 'on';
                app.REFERENCEButton.Enable = 'on';
            end
        end

        % Selection changed function: TYPEOFAUDIOTOBEUSEDButtonGroup
        function TYPEOFAUDIOTOBEUSEDButtonGroupSelectionChanged(app, event)
           %selectedButton = app.TYPEOFAUDIOTOBEUSEDButtonGroup.SelectedObject;
            if app.RECORDEDAUDIOFILEButton.Value
                app.BROWSEButton_2.Enable = 'on';
                app.RECORDButton_2.Enable = 'off';
                app.STOPButton_2.Enable = 'off';
            elseif app.RECORDLIVEButton.Value
                app.BROWSEButton_2.Enable = 'off';
                app.RECORDButton_2.Enable = 'on';
                app.STOPButton_2.Enable = 'on';
            end
        end

        % Button pushed function: DETECTCHORDSButton
        function DETECTCHORDSButtonPushed(app, event)
                mod= py.importlib.import_module("chroma")
                c=py.chroma.chromagram(app.EditField_3.Value);
                h=py.chroma.chordgram(c);
                wsm=py.chroma.chord_sequence(h);
                h1=py.chroma.smoothed_chordgram(h);
                sm =py.chroma.chord_sequence(h1);
                per=py.chroma.accuracy(wsm,sm);
    
                H=py.chroma.new_list(h);
                H=cell(H);
                H=cellfun(@double,H);
                a={};
                len=size(wsm);
    
                for i=1:(len(2))
                    a=[a, char(wsm(i))];
                end
    
                R=grp2idx(a);
                R=reshape(R,1,len(2));
                
                
                scatter(app.UIAxes2,H,R)
                app.UIAxes2.xlabel("Frames")
                app.UIAxes2.ylabel("Chords")
                app.UIAxes2.title("Chords Detection")
                yticks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16])
                app.UIAxes.YtickLabels = ({'Bm','C','D','F#','E','F','G#','G','A','B','C#','NC','Cm','C#m','Dm','D#m'})


        end

        % Value changed function: PLOTTYPEDropDown
        function PLOTTYPEDropDownValueChanged(app, event)
            value = app.PLOTTYPEDropDown.Value;
            
        end

        % Button pushed function: SHIFTPITCHButton
        function SHIFTPITCHButtonPushed(app, event)
            func(app);
        end

        % Button pushed function: DETECTKEYButton
        function DETECTKEYButtonPushed(app, event)
            Key = ComputeKey(app.modifiedxt,app.Fs)
            app.KEYEditField.Value = Key
        end

        % Button pushed function: GENERATEMIDIFILEButton
        function GENERATEMIDIFILEButtonPushed(app, event)
%             f = uifigure;
%     d = uiprogressdlg(app.UIFigure,'Title','Computing SVD',...
%         'Indeterminate','on')      
app.Fs
app.path
app.file
app.EditField_3.Value

Convert_to_midi('piano.wav',app.modifiedxt,app.Fs)
%                close(d);
        end

        % Button pushed function: BROWSEButton_3
        function BROWSEButton_3Pushed(app, event)
            [file_sheet,path_sheet] = uigetfile('*.mid');
            app.music_sheet= file_sheet
            app.EditField_8.Value = file_sheet;
            
        end

        % Button pushed function: DISPLAYMUSICSHEETButton
        function DISPLAYMUSICSHEETButtonPushed(app, event)
            mod=py.importlib.import_module("Sheet_music_app");
            py.importlib.reload(mod);
            py.Sheet_music_app.open_sheet(app.music_sheet)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PRACTICESINGINGTab = uitab(app.TabGroup);
            app.PRACTICESINGINGTab.Title = 'PRACTICE SINGING';
            app.UIAxes1 = uiaxes(app.PRACTICESINGINGTab);
            title(app.UIAxes1, 'PITCH PLOT')
            xlabel(app.UIAxes1, 'TIME')
            ylabel(app.UIAxes1, 'PITCH')
            app.UITable = uitable(app.PRACTICESINGINGTab);
            app.UITable.ColumnName = {'NOTE/PITCH'; 'DURATION'};
            app.UITable.RowName = {};
            app.UPLOADAUDIOTABLEButtonGroup = uibuttongroup(app.PRACTICESINGINGTab);
            app.UPLOADAUDIOTABLEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UPLOADAUDIOTABLEButtonGroupSelectionChanged, true);
            app.UPLOADAUDIOTABLEButtonGroup.Title = 'UPLOAD AUDIO/TABLE';
            app.UPLOADAREFERENCEAUDIOFILEButton = uiradiobutton(app.UPLOADAUDIOTABLEButtonGroup);
            app.UPLOADAREFERENCEAUDIOFILEButton.Text = 'UPLOAD A REFERENCE AUDIO FILE';
            app.UPLOADAREFERENCEAUDIOFILEButton.Value = true;
            app.UPLOADTABLEWITHPITCHTIMEDETAILSButton = uiradiobutton(app.UPLOADAUDIOTABLEButtonGroup);
            app.UPLOADTABLEWITHPITCHTIMEDETAILSButton.Text = 'UPLOAD TABLE WITH PITCH TIME DETAILS';
            app.BROWSEButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.BROWSEButton.ButtonPushedFcn = createCallbackFcn(app, @BROWSEButtonPushed, true);
            app.BROWSEButton.Text = 'BROWSE';
            app.EditField = uieditfield(app.PRACTICESINGINGTab, 'text');
            app.SELECTNOTEDropDownLabel = uilabel(app.PRACTICESINGINGTab);
            app.SELECTNOTEDropDownLabel.Text = 'SELECT NOTE';
            app.SELECTNOTEDropDown = uidropdown(app.PRACTICESINGINGTab);
            app.SELECTNOTEDropDown.Items = {'SILENCE', 'C1', 'C#1', 'D1', 'D#1', 'E1', 'F1', 'F#1', 'G1', 'G#1', 'A1', 'A#1', 'B1', 'C2', 'C#2', 'D2', 'D#2', 'E2', 'F2', 'F#2', 'G2', 'G#2', 'A2', 'A#2', 'B2', 'C3', 'C#3', 'D3', 'D#3', 'E3', 'F3', 'F#3', 'G3', 'G#3', 'A3', 'A#3', 'B3', 'C4', 'C#4', 'D4', 'D#4', 'E4', 'F4', 'F#4', 'G4', 'G#4', 'A4', 'A#4', 'B4', 'C5', 'C#5', 'D5', 'D#5', 'E5', 'F5', 'F#5', 'G5', 'G#5', 'A5', 'A#5', 'B5', 'C6', 'C#6', 'D6', 'D#6', 'E6', 'F6', 'F#6', 'G6', 'G#6', 'A6', 'A#6', 'B6'};
            app.SELECTNOTEDropDown.Value = 'SILENCE';
            app.DURATIONinsEditFieldLabel = uilabel(app.PRACTICESINGINGTab);
            app.DURATIONinsEditFieldLabel.Text = 'DURATION(in s)';
            app.DURATIONinsEditField = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.DURATIONinsEditField.Limits = [0 Inf];
            app.UPLOADButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Enable = 'off';
            app.UPLOADButton.Text = 'UPLOAD';
            app.DONEButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.DONEButton.ButtonPushedFcn = createCallbackFcn(app, @DONEButtonPushed, true);
            app.DONEButton.Enable = 'off';
            app.DONEButton.Text = 'DONE';
            app.RECORDOPTIONPanel = uipanel(app.PRACTICESINGINGTab);
            app.RECORDOPTIONPanel.Title = 'RECORD OPTION';
            app.RECORDButton = uibutton(app.RECORDOPTIONPanel, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.STOPButton = uibutton(app.RECORDOPTIONPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.PLAYOPTIONPanel = uipanel(app.PRACTICESINGINGTab);
            app.PLAYOPTIONPanel.Title = 'PLAY OPTION';
            app.RECORDEDButton = uibutton(app.PLAYOPTIONPanel, 'push');
            app.RECORDEDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDEDButtonPushed, true);
            app.RECORDEDButton.Text = 'RECORDED';
            app.REFERENCEButton = uibutton(app.PLAYOPTIONPanel, 'push');
            app.REFERENCEButton.ButtonPushedFcn = createCallbackFcn(app, @REFERENCEButtonPushed, true);
            app.REFERENCEButton.Text = 'REFERENCE';
            app.OFERRORTHRESHOLDALLOWEDLabel = uilabel(app.PRACTICESINGINGTab);
            app.OFERRORTHRESHOLDALLOWEDLabel.Text = {'% OF ERROR '; 'THRESHOLD'; ' ALLOWED'};
            app.OFERRORTHRESHOLDALLOWEDSlider = uislider(app.PRACTICESINGINGTab);
            app.OFERRORTHRESHOLDALLOWEDSlider.Limits = [0.25 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.MajorTicks = [2 4 6 8 10 12 14 16 18 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.ValueChangingFcn = createCallbackFcn(app, @OFERRORTHRESHOLDALLOWEDSliderValueChanging, true);
            app.OFERRORTHRESHOLDALLOWEDSlider.MinorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6 6.25 6.5 6.75 7 7.25 7.5 7.75 8 8.25 8.5 8.75 9 9.25 9.5 9.75 10 10.25 10.5 10.75 11 11.25 11.5 11.75 12 12.25 12.5 12.75 13 13.25 13.5 13.75 14 14.25 14.5 14.75 15 15.25 15.5 15.75 16 16.25 16.5 16.75 17 17.25 17.5 17.75 18 18.25 18.5 18.75 19 19.25 19.5 19.75 20];
            app.OFERRORTHRESHOLDALLOWEDSlider.Value = 0.25;
            app.EditField_2 = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.EditField_2.Limits = [0 20];
            app.ACCURACYISEditFieldLabel = uilabel(app.PRACTICESINGINGTab);
            app.ACCURACYISEditFieldLabel.Text = 'ACCURACY IS';
            app.ACCURACYISEditField = uieditfield(app.PRACTICESINGINGTab, 'numeric');
            app.CHECKACCURACYButton = uibutton(app.PRACTICESINGINGTab, 'push');
            app.CHECKACCURACYButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKACCURACYButtonPushed, true);
            app.CHECKACCURACYButton.Text = 'CHECK ACCURACY';
            app.PLOTPanel = uipanel(app.PRACTICESINGINGTab);
            app.PLOTPanel.Title = 'PLOT ';
            app.REFERENCEButton_2 = uibutton(app.PLOTPanel, 'push');
            app.REFERENCEButton_2.ButtonPushedFcn = createCallbackFcn(app, @REFERENCEButton_2Pushed, true);
            app.REFERENCEButton_2.Text = 'REFERENCE';
            app.RECORDEDButton_2 = uibutton(app.PLOTPanel, 'push');
            app.RECORDEDButton_2.ButtonPushedFcn = createCallbackFcn(app, @RECORDEDButton_2Pushed, true);
            app.RECORDEDButton_2.Text = 'RECORDED';
            app.SUPERIMPOSEButton = uibutton(app.PLOTPanel, 'push');
            app.SUPERIMPOSEButton.ButtonPushedFcn = createCallbackFcn(app, @SUPERIMPOSEButtonPushed, true);
            app.SUPERIMPOSEButton.Text = 'SUPERIMPOSE';
            app.CLEARButton = uibutton(app.PLOTPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.RESTARTRERECORDPanel = uipanel(app.PRACTICESINGINGTab);
            app.RESTARTRERECORDPanel.Title = 'RESTART/RERECORD';
            app.RESTARTButton = uibutton(app.RESTARTRERECORDPanel, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Text = 'RESTART';
            app.RERECORDButton = uibutton(app.RESTARTRERECORDPanel, 'push');
            app.RERECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RERECORDButtonPushed, true);
            app.RERECORDButton.Text = 'RERECORD';
            app.PITCHTUNERCORRECTORTab = uitab(app.TabGroup);
            app.PITCHTUNERCORRECTORTab.Title = 'PITCH TUNER/CORRECTOR';
            app.UIAxes2 = uiaxes(app.PITCHTUNERCORRECTORTab);
            title(app.UIAxes2, 'PITCH/WAVEFORM PLOT')
            xlabel(app.UIAxes2, 'TIME')
            ylabel(app.UIAxes2, 'PITCH/AMPLITUDE')
            app.PLOTPanel_2 = uipanel(app.PITCHTUNERCORRECTORTab);
            app.PLOTPanel_2.Title = 'PLOT ';
            app.ORIGINALButton = uibutton(app.PLOTPanel_2, 'push');
            app.ORIGINALButton.ButtonPushedFcn = createCallbackFcn(app, @ORIGINALButtonPushed, true);
            app.ORIGINALButton.Text = 'ORIGINAL';
            app.MODIFIEDButton = uibutton(app.PLOTPanel_2, 'push');
            app.MODIFIEDButton.ButtonPushedFcn = createCallbackFcn(app, @MODIFIEDButtonPushed, true);
            app.MODIFIEDButton.Text = 'MODIFIED';
            app.SUPERIMPOSEButton_2 = uibutton(app.PLOTPanel_2, 'push');
            app.SUPERIMPOSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SUPERIMPOSEButton_2Pushed, true);
            app.SUPERIMPOSEButton_2.Text = 'SUPERIMPOSE';
            app.CLEARButton_2 = uibutton(app.PLOTPanel_2, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Text = 'CLEAR';
            app.EDITINGOPTIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.EDITINGOPTIONPanel.Title = 'EDITING OPTION';
            app.CUTButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.CUTButton.ButtonPushedFcn = createCallbackFcn(app, @CUTButtonPushed, true);
            app.CUTButton.Text = 'CUT';
            app.REPLICATEButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.REPLICATEButton.ButtonPushedFcn = createCallbackFcn(app, @REPLICATEButtonPushed, true);
            app.REPLICATEButton.Text = 'REPLICATE';
            app.DELETEButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.JOINButton = uibutton(app.EDITINGOPTIONPanel, 'push');
            app.JOINButton.ButtonPushedFcn = createCallbackFcn(app, @JOINButtonPushed, true);
            app.JOINButton.Text = 'JOIN';
            app.PLOTTYPEDropDownLabel = uilabel(app.PITCHTUNERCORRECTORTab);
            app.PLOTTYPEDropDownLabel.Text = 'PLOT TYPE';
            app.PLOTTYPEDropDown = uidropdown(app.PITCHTUNERCORRECTORTab);
            app.PLOTTYPEDropDown.Items = {'PITCH', 'WAVEFORM'};
            app.PLOTTYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @PLOTTYPEDropDownValueChanged, true);
            app.PLOTTYPEDropDown.Value = 'PITCH';
            app.TYPEOFAUDIOTOBEUSEDButtonGroup = uibuttongroup(app.PITCHTUNERCORRECTORTab);
            app.TYPEOFAUDIOTOBEUSEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TYPEOFAUDIOTOBEUSEDButtonGroupSelectionChanged, true);
            app.TYPEOFAUDIOTOBEUSEDButtonGroup.Title = 'TYPE OF AUDIO TO BE USED';
            app.RECORDEDAUDIOFILEButton = uiradiobutton(app.TYPEOFAUDIOTOBEUSEDButtonGroup);
            app.RECORDEDAUDIOFILEButton.Text = 'RECORDED AUDIO FILE';
            app.RECORDEDAUDIOFILEButton.Value = true;
            app.RECORDLIVEButton = uiradiobutton(app.TYPEOFAUDIOTOBEUSEDButtonGroup);
            app.RECORDLIVEButton.Text = 'RECORD LIVE';
            app.BROWSEButton_2 = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.BROWSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @BROWSEButton_2Pushed, true);
            app.BROWSEButton_2.Text = 'BROWSE';
            app.EditField_3 = uieditfield(app.PITCHTUNERCORRECTORTab, 'text');
            app.PLAYOPTIONPanel_2 = uipanel(app.PITCHTUNERCORRECTORTab);
            app.PLAYOPTIONPanel_2.Title = 'PLAY OPTION';
            app.MODIFIEDAUDIOButton = uibutton(app.PLAYOPTIONPanel_2, 'push');
            app.MODIFIEDAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @MODIFIEDAUDIOButtonPushed, true);
            app.MODIFIEDAUDIOButton.Text = 'MODIFIED AUDIO';
            app.ORIGINALAUDIOButton_2 = uibutton(app.PLAYOPTIONPanel_2, 'push');
            app.ORIGINALAUDIOButton_2.ButtonPushedFcn = createCallbackFcn(app, @ORIGINALAUDIOButton_2Pushed, true);
            app.ORIGINALAUDIOButton_2.Text = 'ORIGINAL AUDIO';
            app.SHIFTNOTEPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.SHIFTNOTEPanel.Title = 'SHIFT NOTE';
            app.SHIFTPITCHButton = uibutton(app.SHIFTNOTEPanel, 'push');
            app.SHIFTPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTPITCHButtonPushed, true);
            app.SHIFTPITCHButton.Text = 'SHIFT PITCH';
            app.SAVEUNDOMODIFICATIONPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.SAVEUNDOMODIFICATIONPanel.Title = 'SAVE/UNDO MODIFICATION';
            app.Label = uilabel(app.SAVEUNDOMODIFICATIONPanel);
            app.Label.Text = '';
            app.SAVEButton = uibutton(app.SAVEUNDOMODIFICATIONPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.UNDOButton = uibutton(app.SAVEUNDOMODIFICATIONPanel, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Text = 'UNDO';
            app.CHECKButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.CHECKButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKButtonPushed, true);
            app.CHECKButton.Text = 'CHECK';
            app.SCALEEditFieldLabel = uilabel(app.PITCHTUNERCORRECTORTab);
            app.SCALEEditFieldLabel.Text = 'SCALE';
            app.SCALEEditField = uieditfield(app.PITCHTUNERCORRECTORTab, 'text');
            app.NOTESEditFieldLabel = uilabel(app.PITCHTUNERCORRECTORTab);
            app.NOTESEditFieldLabel.Text = 'NOTES';
            app.NOTESEditField = uieditfield(app.PITCHTUNERCORRECTORTab, 'text');
            app.DETECTCHORDSButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.DETECTCHORDSButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTCHORDSButtonPushed, true);
            app.DETECTCHORDSButton.Text = 'DETECT CHORDS';
            app.ADDAUDIOEFFECTSPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.ADDAUDIOEFFECTSPanel.Title = 'ADD AUDIO EFFECTS';
            app.EditField_4 = uieditfield(app.ADDAUDIOEFFECTSPanel, 'numeric');
            app.VIBRATOSliderLabel = uilabel(app.ADDAUDIOEFFECTSPanel);
            app.VIBRATOSliderLabel.Text = {'VIBRATO'; ''};
            app.VIBRATOSlider = uislider(app.ADDAUDIOEFFECTSPanel);
            app.VIBRATOSlider.Limits = [1 10];
            app.VIBRATOSlider.ValueChangingFcn = createCallbackFcn(app, @VIBRATOSliderValueChanging, true);
            app.VIBRATOSlider.Value = 1;
            app.APPLYButton_3 = uibutton(app.ADDAUDIOEFFECTSPanel, 'push');
            app.APPLYButton_3.ButtonPushedFcn = createCallbackFcn(app, @APPLYButton_3Pushed, true);
            app.APPLYButton_3.Text = 'APPLY';
            app.EditField_7 = uieditfield(app.ADDAUDIOEFFECTSPanel, 'numeric');
            app.TEMPOFACTORLabel = uilabel(app.ADDAUDIOEFFECTSPanel);
            app.TEMPOFACTORLabel.Text = {'TEMPO '; 'FACTOR'};
            app.TEMPOFACTORSlider = uislider(app.ADDAUDIOEFFECTSPanel);
            app.TEMPOFACTORSlider.Limits = [0 4];
            app.TEMPOFACTORSlider.MajorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4];
            app.TEMPOFACTORSlider.ValueChangingFcn = createCallbackFcn(app, @TEMPOFACTORSliderValueChanging, true);
            app.TEMPOFACTORSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.TEMPOFACTORSlider.Value = 0.1;
            app.APPLYButton = uibutton(app.ADDAUDIOEFFECTSPanel, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.RECORDOPTIONPanel_2 = uipanel(app.PITCHTUNERCORRECTORTab);
            app.RECORDOPTIONPanel_2.Title = 'RECORD OPTION';
            app.RECORDButton_2 = uibutton(app.RECORDOPTIONPanel_2, 'push');
            app.RECORDButton_2.ButtonPushedFcn = createCallbackFcn(app, @RECORDButton_2Pushed, true);
            app.RECORDButton_2.Text = 'RECORD';
            app.STOPButton_2 = uibutton(app.RECORDOPTIONPanel_2, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.SELECTAPARTOFPITCHButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.SELECTAPARTOFPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTAPARTOFPITCHButtonPushed, true);
            app.SELECTAPARTOFPITCHButton.Text = {'SELECT A '; 'PART OF PITCH'};
            app.DETECTKEYButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.DETECTKEYButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTKEYButtonPushed, true);
            app.DETECTKEYButton.Text = {'DETECT KEY'; ''};
            app.KEYEditFieldLabel = uilabel(app.PITCHTUNERCORRECTORTab);
            app.KEYEditFieldLabel.Text = {'KEY'; ''};
            app.KEYEditField = uieditfield(app.PITCHTUNERCORRECTORTab, 'text');
            app.GENERATEMIDIFILEButton = uibutton(app.PITCHTUNERCORRECTORTab, 'push');
            app.GENERATEMIDIFILEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEMIDIFILEButtonPushed, true);
            app.GENERATEMIDIFILEButton.Text = {'GENERATE MIDI '; 'FILE'; ''};
            app.MUSICSHEETPanel = uipanel(app.PITCHTUNERCORRECTORTab);
            app.MUSICSHEETPanel.Title = 'MUSIC SHEET';
            app.EditField_8 = uieditfield(app.MUSICSHEETPanel, 'text');
            app.BROWSEButton_3 = uibutton(app.MUSICSHEETPanel, 'push');
            app.BROWSEButton_3.ButtonPushedFcn = createCallbackFcn(app, @BROWSEButton_3Pushed, true);
            app.BROWSEButton_3.Text = {'BROWSE'; ''};
            app.DISPLAYMUSICSHEETButton = uibutton(app.MUSICSHEETPanel, 'push');
            app.DISPLAYMUSICSHEETButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYMUSICSHEETButtonPushed, true);
            app.DISPLAYMUSICSHEETButton.Text = 'DISPLAY MUSIC SHEET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp8thsem14_06_2020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end